classdef FourDFlowAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OCMR4DFlowPostProcessingToolUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CurrentDirectoryEditFieldLabel  matlab.ui.control.Label
        CurrentDirectoryEditField       matlab.ui.control.EditField
        GoButton                        matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        Lamp                            matlab.ui.control.Lamp
        FailedLabel                     matlab.ui.control.Label
        MainSettingsPanel               matlab.ui.container.Panel
        GroupCheckBox                   matlab.ui.control.CheckBox
        ContourDropDown                 matlab.ui.control.DropDown
        ContourDropDownLabel            matlab.ui.control.Label
        SelectDirectoryButton           matlab.ui.control.Button
        SeriesIDsEditFieldLabel         matlab.ui.control.Label
        SeriesIDsEditField              matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Tree                            matlab.ui.container.Tree
        GroupDirectoryNode              matlab.ui.container.TreeNode
        Study1Node                      matlab.ui.container.TreeNode
        DICOMNode                       matlab.ui.container.TreeNode
        Series1Node                     matlab.ui.container.TreeNode
        Series2Node                     matlab.ui.container.TreeNode
        SeriesNNode                     matlab.ui.container.TreeNode
        contourscvi42wsxNode            matlab.ui.container.TreeNode
        Study2Node                      matlab.ui.container.TreeNode
        Study3Node                      matlab.ui.container.TreeNode
        ExampleFileArchitectureLabel    matlab.ui.control.Label
        OCMR4DFlowPostProcessingToolboxVersion20InstructionsLabel  matlab.ui.control.Label
        Welcometoour4DFlowPostProcessingToolboxLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AuthorsLabel                    matlab.ui.control.Label
        PreferencesTab                  matlab.ui.container.Tab
        ClearLogButton                  matlab.ui.control.Button
        AdvancedSettingsPanel           matlab.ui.container.Panel
        DebugErrorsCheckBox             matlab.ui.control.CheckBox
        DICOMStudyEditField             matlab.ui.control.NumericEditField
        DICOMStudyEditFieldLabel        matlab.ui.control.Label
        ClearDicomCacheButton           matlab.ui.control.Button
        DicomFolderLabel                matlab.ui.control.Label
        DicomFolderEditField            matlab.ui.control.EditField
        ForceReloadCheckBox             matlab.ui.control.CheckBox
        PhaseUnwrappingCheckBox         matlab.ui.control.CheckBox
        NoiseReductionCheckBox          matlab.ui.control.CheckBox
        WriteFlowDataCheckBox           matlab.ui.control.CheckBox
        AnalyseFlowCheckBox             matlab.ui.control.CheckBox
        OutputSettingsPanel             matlab.ui.container.Panel
        OpenCurrentDirectoryButton      matlab.ui.control.Button
        FlowVisualsLabel                matlab.ui.control.Label
        PlotSelector                    matlab.ui.control.DropDown
        ShowSlicesCheckBox              matlab.ui.control.CheckBox
        ImageTransparencyEditFieldLabel  matlab.ui.control.Label
        ImageTransparencyEditField      matlab.ui.control.NumericEditField
        MaxStreamlinestoPlotEditFieldLabel  matlab.ui.control.Label
        MaxStreamlinestoPlotEditField   matlab.ui.control.NumericEditField
        SaveAllPlotsCheckBox            matlab.ui.control.CheckBox
        StatisticsFileExtDropDown       matlab.ui.control.DropDown
        StatisticsFileExtDropDownLabel  matlab.ui.control.Label
        ExportMoviePanel                matlab.ui.container.Panel
        MovieFilenameEditField          matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        MovieFPSSpinner                 matlab.ui.control.Spinner
        FPSLabel                        matlab.ui.control.Label
        WriteMovieButton                matlab.ui.control.Button
        SelectNoneButton                matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        ListofStudiestoAnalyseListBox   matlab.ui.control.ListBox
        ListofStudiestoAnalyseListBoxLabel  matlab.ui.control.Label
        GenerateStatsFileFromSelectedButton  matlab.ui.control.Button
        OxfordCentreforMagneticResonanceOCMRUniversityofOxfordLabel  matlab.ui.control.Label
        DialogBoxTextArea               matlab.ui.control.TextArea
        VorticityTimeTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SaveVelocityPlotButton_2        matlab.ui.control.Button
        UIAxes_OmegaT                   matlab.ui.control.UIAxes
        VelocityTimeTab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        SaveVelocityPlotButton          matlab.ui.control.Button
        UIAxes_Vel                      matlab.ui.control.UIAxes
        VortexSizeTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SaveVelocityPlotButton_3        matlab.ui.control.Button
        UIAxes_VS                       matlab.ui.control.UIAxes
        StasisHistogramTab              matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SaveVelocityPlotButton_4        matlab.ui.control.Button
        UIAxes_SH                       matlab.ui.control.UIAxes
        VelocityHistogramTab            matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        SaveVelocityPlotButton_5        matlab.ui.control.Button
        UIAxes_VH                       matlab.ui.control.UIAxes
        FlowVisualisationTab            matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        SliceSpinnerLabel               matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        FrameLabel                      matlab.ui.control.Label
        IntegrateoverframesCheckBox     matlab.ui.control.CheckBox
        FrameSlider                     matlab.ui.control.Slider
        SaveVelocityPlotButton_6        matlab.ui.control.Button
        UIAxes_Flow3                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        DefaultDicomDirName = 'DICOM'; % The name of the folder containing the dicom dirs
        Contour_Filename = ''; % Atrial contours location
        directoryPath = '';  %Where the magic is
        parts_saved % Analysed accessible flow struct
        saved_mf % Saved data
        saved_mf_cont;
        integralOfVorticity % The area under the vorticity-time curve
        serFlow=[0,0,0,0]% Parsed flow series
        StudyNumber=1; % Number of study
        dicomSeriesInfo % Loaded patient dicom series info
        numCardiacFrames=28; % Number of cardiac frames, sensible default
        writeStatsToFile=1; %set to false explicitly externally if desired
        stats=struct();
        time_stamps;
        vorticity_mag;
        vorticity_mag_atria;
        vorticity_map_mask;
        vortex_sz_ml;
        stasis_atria;
        peak_atria;
        flow_mag_atria;
        vox_vol;
        lambda2_atria;
        vorticity_map;
        showAndUpdatePlots=1 % Turn off to avoid updating plots and save time
        SavePlots=0; % If true, save generated plots in the relevant directory.
        SaveAsName; % Filename to save plots to
        names; % list of study names for multiple studies
        groupDirectoryPath; % path to directory containing multiple studies
        total_seqtimemmss; % Approximate sequence time calculated from dicom header in mm:ss
        total_seqtimeseconds; % Approximate sequence time calculated from dicom header in seconds
        FailedDatasets; % List of datasets where analysis failed for whatever reason
        StartDate; % Date analysis started
        ContoursonPhaseImage; % Keeps track of whether the contouring was performed on a phase image or not
    end

    properties (Access = private)
        currentCardiacFrame=1; % The current cardiac frame, ditto
        ResultsAreDisplayed=0;
        maxStreamlinesToPlot=25 % Maximum number of streamlines to show
        showMagnitudeOnVisualisation=1; % Show magnitude slices on the atrial mask
        SliceAlphaValue = 0.2; % Opacity in 3D plot
        ThreeDeeCameraPositions; % View in 3D plot
        PreferencesLocation; % OS-dependent preferences location, set by startupFcn
        toolVersion = 2.0; % update this
        StatsFileExt = '.xlsx';
        plotType='Streamlines'; % Plot to show in figure 3
        coordsX;
        coordsY;
        coordsZ;
        PeeThree;
        freeB;
        delTri;
        caxisMin;
        cbH; %colorbar handle
        currentSlice=1; % For 2D contour display
        reverseCmap=1; %reverse the cmap
        scaleUpFactor=4; %Bicubic interpolation for contour display
        debugDisplay=false; % if set to 'true', run 'keyboard' inside every try/catch expression at the end...
    end

    methods (Access = private)
        function myUpdateAppLayout(app, event)
            currentFigureWidth = app.OCMR4DFlowPostProcessingToolUIFigure.Position(3);
            currentFigureHeight = app.OCMR4DFlowPostProcessingToolUIFigure.Position(4);
            %             if(currentFigureWidth <= app.onePanelWidth)
            %                 % Change to a 2x1 grid
            %                 app.GridLayout.RowHeight = {481, 481}; % JK change these on WIN
            %                 app.GridLayout.ColumnWidth = {'1x'};
            %                 app.RightPanel.Layout.Row = 2;
            %                 app.RightPanel.Layout.Column = 1;
            %             else
            %                 % Change to a 1x2 grid
            %                 app.GridLayout.RowHeight = {'1x'};
            %                 app.GridLayout.ColumnWidth = {220, '1x'};
            %                 app.RightPanel.Layout.Row = 1;
            %                 app.RightPanel.Layout.Column = 2;
            %             end
            %Resize tab group to match size
            %             currentTabGroupSize = app.TabGroup.Position;
            %             correctTabGroupSize = currentTabGroupSize;
            %             correctTabGroupSize(3) = currentFigureWidth - app.GridLayout.ColumnWidth{1} -20;
            %             correctTabGroupSize(4) = currentFigureHeight - 40; %Menubar height
            %             app.TabGroup.Position = correctTabGroupSize;
            %Fix (probably hidden) slice-spinner position
            %app.SliceSpinner.Position(3)=46;
            %app.SliceSpinnerLabel.Position(3)=31;
        end


        function results = formatPlots(app, figureToFormat)
            set(figureToFormat, 'color','w');
            grid(figureToFormat,'on');
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % This is a hack for overlaying two coloured images...
        % Taken from my own (JRM) (GPLv3) code, github.com/NeutralKaon
        function  disp4d_overlay(app, im_underlay,im_overlay,options)

            im_c=im_overlay;


            %%Parse options
            defaultOptions.hfig = 2;
            defaultOptions.hax = [];
            defaultOptions.do_normslice = 1;
            defaultOptions.orient = 1;
            defaultOptions.overlay_flag = 'abs';%not actually used!

            % choose a colourmap
            defaultOptions.cmap = 'jet';%'parula';

            defaultOptions.bgnd_bright = 0.6;
            defaultOptions.gamma_background = 0.5;
            defaultOptions.gamma_foreground = 1;
            defaultOptions.fov_x = 1.0;
            defaultOptions.fov_y = 1.0;
            defaultOptions.zoomFactor=1.5;
            defaultOptions.cutBottom=0.05;
            defaultOptions.cutTop=1;

            defaultOptions.do_overlay_writing=false;
            defaultOptions.overlayString='';
            defaultOptions.times=[];
            defaultOptions.interpFactor=3;


            Options=mergeOptions(defaultOptions,options);
            im_max = max(abs(im_c(:)));
            if strcmp(Options.overlayString,'times')
                Options.doTimes=true;
            end


            im_over = im_c(:,:,1);

            % For each frame, we scale to the maximum over the entire time series.

            max_scale = (1./(max(abs(im_over(:))) / im_max));
            f_bot = max_scale * Options.cutBottom;
            f_top = max_scale * Options.cutTop;

            app.disp4d_overlay_helper_wrapper(im_over, im_underlay, ...
                Options.hfig, Options.hax, Options.do_normslice, Options.orient, ...
                Options.overlay_flag, f_bot, f_top, ...
                Options.cmap, Options.bgnd_bright, Options.gamma_background,...
                Options.gamma_foreground,Options.fov_x, Options.fov_y, ...
                Options);

            h=colorbar(app.UIAxes_Flow3);
            h.Limits=[512 769];
            %Again, a dodgy hack to give the apparance of normality when
            %reality is far from normal...
            noOfTicksShown = length(h.TickLabels);
            % JK This was giving awkward values
            %correspondingActualValues=interp1([Options.cutBottom Options.cutTop],[f_bot*app.caxisMin(1) f_top*app.caxisMin(end-1)],linspace(Options.cutBottom,Options.cutTop,noOfTicksShown));
            correspondingActualValues = 0:10:40; % JK ?
            h.TickLabels = cellfun(@(x) sprintf('%2.1f',x), num2cell(correspondingActualValues), 'UniformOutput', false);
            if app.IntegrateoverframesCheckBox.Value
                h.Label.String='Integrated Vorticity (radians)';
            else
                h.Label.String='Vorticity (radians/s)';
            end

        end

        function disp4d_overlay_helper_wrapper(app,im1, im2, hfig, hax, do_normslice, orientation_flag, overlay_flag, f_bot, f_top, cmap_flag, bgnd_bright, gamma_background, gamma_foreground, fov_scale_x, fov_scale_y,Options)
            im_max = max(im1(:));
            im2_max = app.caxisMin(end);
            %im2_max = max(abs(im2(:))); %This should be the max across the cardiac cycle

            im_wl = [f_bot*im_max f_top*im_max];
            im2_wl = [0 im2_max];
            app.disp4d_overlay_helper(im1,im_wl,abs(im2),im2_wl,cmap_flag,bgnd_bright,gamma_background,gamma_foreground,Options);

        end
        function [overlay_colormap, h] = disp4d_overlay_helper(app,im1,wl1,im2,wl2,cmap,bgnd_bright,gamma_background,gamma_foreground, Options)

            na = nargin-1;
            if(na < 5)
                cmap = 1;
            end

            if(na < 6)
                bgnd_bright = 0.4;
            end

            if(na < 7)
                gamma_background = 1;
            end

            if(na < 8)
                gamma_foreground = 1;
            end

            % create transparency data
            imAlphaData = (1-bgnd_bright)*ones(size(im1));

            % create image data struct to pass around
            imdata.im1 = im1;
            imdata.im2 = im2;
            imdata.wl1 = wl1;
            imdata.wl2 = wl2;
            imdata.imAlphaData = imAlphaData;

            iim1 = app.make_overlay(imdata);

            % set up colormap
            colormap_full = app.init_colormap(cmap,gamma_background,gamma_foreground);
            colormap(app.UIAxes_Flow3, colormap_full );


        end
        function colormap_full = init_colormap(app, cmap,gamma_background,gamma_foreground)
            switch cmap
                case {1,'hot'}
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = hot(256);
                case {2,'jet'}
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = jet(256);
                case {3, 'cool'}
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = cool(256);
                case {4, 'warm'}
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = warm(256);
                case {5, 'parula'}
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = parula(256);
                otherwise
                    cmap_background = gray(256);
                    cmap_filler = 0*gray(256);
                    cmap_foreground = hot(256);
            end
            if app.reverseCmap
                cmap_foreground = cmap_foreground (end:-1:1,:);
            end
            % apply gamma
            cmap_background = cmap_background.^(gamma_background);
            cmap_foreground = cmap_foreground.^(gamma_foreground);
            colormap_full = [cmap_background; cmap_filler; cmap_foreground];

        end
        function iim1 = make_overlay(app, imdata)
            % display background image
            app.dil(imdata.im2,imdata.wl2,256,0);
            % overlay im1
            hold(app.UIAxes_Flow3,'on');
            iim1 = app.dil(imdata.im1,imdata.wl1,256,512);
            set(iim1,'AlphaData',imdata.imAlphaData);
            hold(app.UIAxes_Flow3,'off');

        end

        function [handle] = dil(app, im, levels, ngray, offset)
            %
            % function di (im, [levels, [[gam], [ngray]]] )
            %
            % script to display an image
            %   - optional levels [min max], otherwise autoscaled from min to max
            %   - optional gamma (default 1.0)
            %   - optional number of grayscales (default 256)
            %
            %

            if (~isreal(im))
                disp('Warning: image contains imaginary data not being displayed.');
            end
            im = real(im);

            % Scale im from 0.0 to 1.0
            if ( nargin-1 < 2 )
                min_lev = min(im(:));
                max_lev = max(im(:));
            else
                if length(levels) ~= 2
                    error('levels parameter must be 2-element array');
                end

                min_lev = levels(1);
                max_lev = levels(2);
                if min_lev > max_lev
                    error('levels(1) must be < levels(2)');
                end
                if ((min(im(:)) < min_lev) || (max(im(:)) > max_lev))
                    %disp('Warning: Image contains levels outside those displayed');
                end
            end

            if (nargin -1 < 4)
                ngray = 256;
            end

            ims = min(1.0, max(0.0, (im - min_lev) / (max_lev - min_lev)));

            handle = image(app.UIAxes_Flow3,ims * ngray + offset);
            %axis(app.UIAxes_Flow3,'off');
            axis(app.UIAxes_Flow3,'image');

        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function results = updateQuiverPlot(app)
            %Compute x/y/z
            sliceThickness = app.dicomSeriesInfo.SliceThickness;
            ps  = app.dicomSeriesInfo.PixelSpacing/1000; %Pixel spacing

            [X, Y, Z] = meshgrid((1:size(app.parts_saved.vars.flow_denoised,2))*ps(1),...
                (1:size(app.parts_saved.vars.flow_denoised,1))*ps(2),...
                (1:size(app.parts_saved.vars.flow_denoised,3))*sliceThickness/1000);


            %extract flow:
            dT = app.currentCardiacFrame;
            U=squeeze(app.parts_saved.vars.flow_denoised(:,:,:,dT,1));%.*(app.parts_saved.vars.maskAtriaMin);
            V=squeeze(app.parts_saved.vars.flow_denoised(:,:,:,dT,2));%.*(app.parts_saved.vars.maskAtriaMin);
            W=squeeze(app.parts_saved.vars.flow_denoised(:,:,:,dT,3));%.*(app.parts_saved.vars.maskAtriaMin);
            app.SliceSpinner.Limits(2)=size(app.parts_saved.vars.flow_denoised,3);

            U=flip(flip(U,1),2);
            V=flip(flip(V,1),2);
            W=flip(flip(W,1),2);
            %I THINK this is due to the differnece between conventions for
            %which way is left/right and up/down, aka "images" vs
            %"matrices" that other matlab functions have, but, honestly,
            %it's a giant PITA and the atrial mask isn't in the atria (and
            %there is very little flow) if you don't do this.


            %Identify "interesting" places to plot
            %[coordsX, coordsY, coordsZ]=ind2sub(size(app.parts_saved.vars.maskAtriaMin),...
            %    find(app.parts_saved.vars.maskAtriaMin));
            %delTri= (delaunayTriangulation(coordsX, coordsY, coordsZ));
            %[freeB , PeeThree] = freeBoundary(delTri);
            switch app.plotType
                case 'Streamlines'
                    app.IntegrateoverframesCheckBox.Visible = 0;
                    colorbar(app.UIAxes_Flow3,'off');
                    Cx = app.coordsX(app.freeB(:,1))*ps(1);
                    Cy = app.coordsY(app.freeB(:,2))*ps(2);
                    Cz = app.coordsZ(app.freeB(:,3))*sliceThickness/1000;

                    %Compute centroid of atria and show slices at this location;
                    magMr=(app.parts_saved.vars.pcmri);
                    tmpStats = regionprops3(app.parts_saved.vars.maskAtriaMin);
                    newCentre = tmpStats.Centroid;
                    colormap(app.UIAxes_Flow3,'gray');

                    if app.showMagnitudeOnVisualisation
                        sliceHandle = slice(app.UIAxes_Flow3, X, Y, Z, ((magMr(:,end:-1:1,:))), ...%TODO -- check atrial orientation...
                            newCentre(2)*ps(2), newCentre(1)*ps(1), newCentre(3)*sliceThickness/1000);
                        for ix=1:length(sliceHandle)
                            sliceHandle(ix).EdgeAlpha=0;
                            sliceHandle(ix).FaceAlpha=app.SliceAlphaValue;
                            sliceHandle(ix).LineStyle='none';
                        end
                    end
                    hold(app.UIAxes_Flow3,'on');
                    colormap(app.UIAxes_Flow3,'gray');
                    %Randomly sample flow along the border of the atria
                    if length(Cx) > app.maxStreamlinesToPlot
                        sIdx = randsample(length(Cx), app.maxStreamlinesToPlot);
                    else
                        sIdx=1:length(Cx);
                    end


                    trisurf(app.freeB, app.PeeThree(:,1)*ps(1), app.PeeThree(:,2)*ps(2), app.PeeThree(:,3)*sliceThickness/1000,...
                        'FaceColor','cyan','FaceAlpha',0.5,'Parent',app.UIAxes_Flow3,...
                        'EdgeAlpha',0.5);


                    %Show the streamlines
                    streamline(X,Y,Z,U,V,W,Cx(sIdx), Cy(sIdx), Cz(sIdx),'Parent',app.UIAxes_Flow3)
                    title(app.UIAxes_Flow3,['Atrial contour and ' num2str(app.maxStreamlinesToPlot) ...
                        ' randomly-chosen streamlines']);
                    %Set camera back to what it was before, if it exists
                    if ~isempty(app.ThreeDeeCameraPositions)
                        app.UIAxes_Flow3.CameraPosition = app.ThreeDeeCameraPositions{1};
                        app.UIAxes_Flow3.Position = app.ThreeDeeCameraPositions{2};
                    end
                case 'Vorticity Border'
                    app.IntegrateoverframesCheckBox.Visible = 0;
                    hold(app.UIAxes_Flow3,'off');
                    %Color the triangulation by vorticity:
                    cmapVorticity=zeros(size(app.PeeThree,1),1);

                    for ix=1:size(app.PeeThree,1)
                        cmapVorticity(ix)=app.parts_saved.vars.vorticity(app.PeeThree(ix,1),app.PeeThree(ix,2),app.PeeThree(ix,3),dT);
                    end

                    %boundaryVorticity=(app.parts_saved.vars.vorticity(PeeThree(:,1),PeeThree(:,2),PeeThree(:,3),:));
                    %caxisMin=quantile(boundaryVorticity(:), [0.1 0.9]);

                    trisurf(app.freeB, app.PeeThree(:,1)*ps(1), app.PeeThree(:,2)*ps(2), app.PeeThree(:,3)*sliceThickness/1000,...
                        cmapVorticity,'FaceAlpha',0.9,'Parent',app.UIAxes_Flow3,...
                        'EdgeAlpha',0.9);

                    % Show the atrial Delunay triangulation
                    colormap(app.UIAxes_Flow3, 'default');
                    caxis(app.UIAxes_Flow3,[app.caxisMin(2) app.caxisMin(10)]);
                    app.cbH=colorbar(app.UIAxes_Flow3,'AxisLocation','in');
                    app.cbH.Label.String = 'Vorticity (radians/s)';
                    title(app.UIAxes_Flow3,'Vorticity shown on the minimal atrial border');
                    %Set camera back to what it was before, if it exists
                    if ~isempty(app.ThreeDeeCameraPositions)
                        app.UIAxes_Flow3.CameraPosition = app.ThreeDeeCameraPositions{1};
                        app.UIAxes_Flow3.Position = app.ThreeDeeCameraPositions{2};
                    end
                case 'Quiver'
                    app.IntegrateoverframesCheckBox.Visible = 0;
                    %Quiver Plot – not very informative but uncomment to see it
                    %too...even if masked to the atria, it's not very useful.
                    %
                    q = quiver3(app.UIAxes_Flow3,X, Y, Z, U, V, W);

                    %// Compute the magnitude of the vectors
                    mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));

                    %// Get the current colormap
                    currentColormap = colormap(app.UIAxes_Flow3);

                    %// Now determine the color to make each arrow using a colormap
                    [~, ~, ind] = histcounts(mags, size(currentColormap, 1));

                    %// Now map this to a colormap to get RGB
                    cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                    cmap(:,:,4) = 255;
                    cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);

                    %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                    set(q.Head, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'

                    %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                    set(q.Tail, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:2,:,:), [], 4).');
                    %Set camera back to what it was before, if it exists
                    if ~isempty(app.ThreeDeeCameraPositions)
                        app.UIAxes_Flow3.CameraPosition = app.ThreeDeeCameraPositions{1};
                        app.UIAxes_Flow3.Position = app.ThreeDeeCameraPositions{2};
                    end
                case 'Vorticity Contours'
                    %This is a HORRIBLE hack as AppDesigner doesn't let you
                    %assign values from axes() at the moment...
                    app.IntegrateoverframesCheckBox.Visible = 1;
                    magMr=(app.parts_saved.vars.PCMRIMag);
                    app.UIAxes_Flow3.reset();
                    hold(app.UIAxes_Flow3,'off');
                    axis(app.UIAxes_Flow3,'off');
                    %Compute magnitude of vorticity in the atrial contour
                    vorticityToPlot=app.parts_saved.vars.vorticity(:,:,app.currentSlice, dT).*app.parts_saved.vars.maskAtriaMin(:,:,app.currentSlice);
                    optionsStruct=struct();
                    magToPlot = magMr(:,:,app.currentSlice,dT);


                    scaleUpFactor = app.scaleUpFactor;
                    magToPlot = imresize(magToPlot, scaleUpFactor, 'Method',"bicubic" );
                    vorticityToPlot = imresize(vorticityToPlot, scaleUpFactor, "Method","bicubic" );

                    %magToPlot = resize(magToPlot, size(magToPlot)*scaleUpFactor );
                    %vorticityToPlot = resize(vorticityToPlot, size(vorticityToPlot)*scaleUpFactor );

                    if app.IntegrateoverframesCheckBox.Value
                        % JK added integrated values
                        int_vorticityToPlot = trapz(app.time_stamps,app.parts_saved.vars.vorticity(:,:,app.currentSlice,:),4).*app.parts_saved.vars.maskAtriaMin(:,:,app.currentSlice);
                        int_magToPlot = trapz(app.time_stamps,magMr(:,:,app.currentSlice,:),4);
                        int_magToPlot = imresize(int_magToPlot, scaleUpFactor, 'Method',"bicubic" ); % JK added integrated values
                        int_vorticityToPlot = imresize(int_vorticityToPlot, scaleUpFactor, "Method","bicubic" ); % JK added integrated values
                        app.disp4d_overlay(int_magToPlot , int_vorticityToPlot,optionsStruct);
                    else
                        app.disp4d_overlay(magToPlot , vorticityToPlot,optionsStruct);
                    end
                    app.UIAxes_Flow3.View=[0 90];


                    %                     fh=imagesc(app.UIAxes_Flow3, magMr(:,:,app.currentSlice, dT));
                    %                     hold(app.UIAxes_Flow3,'on');
                    %                     colormap(app.UIAxes_Flow3, 'gray');
                    %                     axis(app.UIAxes_Flow3,'equal');
                    %                     axis(app.UIAxes_Flow3,'off');

                    %
                    %                     imdata.im1=magMr(:,:,app.currentSlice, dT);
                    %                     imdata.wl1=caxis(app.UIAxes_Flow3);
                    %                     imdata.im2=vorticityToPlot;
                    %                     imdata.wl2=[app.caxisMin(2) app.caxisMin(10)];
                    %                     %Todo: change these to preference properties
                    %                     bgnd_bright=0.4;
                    %                     gamma_background=1;
                    %                     gamma_foreground=1;
                    %                     imdata.imAlphaData = (1-bgnd_bright)*ones(size(imdata.im1));
                    %                     set(fh,'AlphaData',imdata.imAlphaData);
                    %                     image(app.UIAxes_Flow3,imdata.im2)
                    %                     imdata.cmap_background = gray(256);
                    %                     imdata.cmap_filler = 0*gray(256);
                    %                     imdata.cmap_foreground = hot(256);
                    %                     imdata.cmap_background = imdata.cmap_background.^(gamma_background);
                    %                     imdata.cmap_foreground = imdata.cmap_foreground.^(gamma_foreground);
                    %                     imdata.colormap_full = [imdata.cmap_background; imdata.cmap_filler; imdata.cmap_foreground];
                    %                     colormap(app.UIAxes_Flow3, imdata.colormap_full);


                otherwise
                    error('Unknown plot type');
            end

            xlabel(app.UIAxes_Flow3,'x (m)');
            ylabel(app.UIAxes_Flow3,'y (m)');
            zlabel(app.UIAxes_Flow3,'z (m)');

            app.UIAxes_Flow3.Interactions=[rotateInteraction zoomInteraction rulerPanInteraction];

            axis(app.UIAxes_Flow3,'equal');

            hold(app.UIAxes_Flow3,'off');
            app.UIAxes_Flow3.Toolbar.Visible = 'on';


        end

        function drawVorticityPolygon(app)
            sliceThickness = app.dicomSeriesInfo.SliceThickness;
            ps  = app.dicomSeriesInfo.PixelSpacing/1000; %Pixel spacing

            [X, Y, Z] = meshgrid((1:size(app.parts_saved.vars.flow_denoised,2))*ps(1),...
                (1:size(app.parts_saved.vars.flow_denoised,1))*ps(2),...
                (1:size(app.parts_saved.vars.flow_denoised,3))*sliceThickness/1000);


            %extract flow:
            dT = app.currentCardiacFrame;
            %[coordsX, coordsY, coordsZ]=ind2sub(size(app.parts_saved.vars.maskAtriaMin),...
            %    find(app.parts_saved.vars.maskAtriaMin));
            %delTri= (delaunayTriangulation(coordsX, coordsY, coordsZ));
            %[freeB , PeeThree] = freeBoundary(delTri);
            cmapVorticity=zeros(size(app.PeeThree,1),1);

            for ix=1:size(app.PeeThree,1)
                cmapVorticity(ix)=app.parts_saved.vars.vorticity(app.PeeThree(ix,1),app.PeeThree(ix,2),app.PeeThree(ix,3),dT);
            end

            boundaryVorticity=(app.parts_saved.vars.vorticity(app.PeeThree(:,1),app.PeeThree(:,2),app.PeeThree(:,3),:));
            caxisMax=max(boundaryVorticity(:));
            caxisMin=min(boundaryVorticity(:));


            %Show the atrial Delunay triangulation
            %colormap(app.UIAxes_Flow3, 'cool');
            trisurf(app.freeB, app.PeeThree(:,1)*ps(1), app.PeeThree(:,2)*ps(2), app.PeeThree(:,3)*sliceThickness/1000,...
                'FaceColor','cyan','FaceAlpha',0.5,'Parent',app.UIAxes_Flow3,...
                'EdgeAlpha',0.5);
            caxis(app.UIAxes_Flow3,[caxisMin, caxisMax]);
            xlabel(app.UIAxes_Flow3,'x (m)');
            ylabel(app.UIAxes_Flow3,'y (m)');
            zlabel(app.UIAxes_Flow3,'z (m)');
            axis(app.UIAxes_Flow3,'equal');
        end

        function  [value, errorMsg] = getRegistryValue(app, rootKey, registryPath, key)
            %Nicked from https://www.mathworks.com/matlabcentral/fileexchange/73045-windows-registry-utilities?s_tid=srchtitle_registry_4
            % This function is only available on Windows platforms (no registry on Mac/Linux!)
            % Get a registry value
            % e.g. value = getRegistryValue('HKEY_LOCAL_MACHINE', 'Software\Classes\.zip', 'Content Type')
            %      value = getRegistryValue('HKEY_LOCAL_MACHINE\Software\Classes\.zip', 'Content Type')
            %      value = getRegistryValue('HKEY_LOCAL_MACHINE\Software\Classes\.zip', '')
            %
            % If the 2nd output argument (errorMsg) is requested, then registry query errors
            % do not raise an error, but rather return an empty value and the error message.
            % e.g. [value, errorMsg] = getRegistryValue('HKEY_CURRENT_USER', 'No-such-key', '')
            if ~ispc
                error('YMA:getRegistryValue:NotWindows','%s only works within Windows',mfilename)
            end
            % Parse the input args
            if nargin < 2
                registryPath = '';
            end
            if nargin < 3
                key = registryPath;
                [rootKey, registryPath] = strtok(rootKey,'/\');
                if isempty(registryPath)
                    registryPath = key;
                    key = '';
                else
                    registryPath(1) = '';  % remove leading '\'
                end
            end
            % Query registry using Matlab's builtin winqueryreg function (trap errors)
            try
                value = [];  % initialize
                try
                    value = winqueryreg(rootKey, registryPath, key);
                    if ischar(value)
                        val = str2num(value); %#ok<ST2NM>
                        if ~isempty(val), value = val; end
                    end
                catch
                    command = ['reg query ' rootKey '\' registryPath];
                    if ~isempty(key), command = [command ' /v ' key]; end
                    [status, msg] = system(command);
                    if status
                        error('YMA:getRegistryValue:OpFailed',msg)
                    end
                    msg = strtrim(strsplit(strtrim(msg), char(10))); %#ok<CHARTEN>
                    if isempty(key)
                        value = struct;
                        for idx = 2 : numel(msg)
                            try
                                keyStr = msg{idx};
                                keyName = genvarname(strtok(keyStr));
                                keyValue = app.getKeyValue(keyStr);
                                value.(keyName) = keyValue;
                            catch
                                % ignore this key (possibly a subkey with no value)
                            end
                        end
                    else
                        try
                            value = app.getKeyValue(msg{2});
                        catch
                            %return [] upon key value parsing error
                        end
                    end
                end
                if nargout > 1
                    errorMsg = '';
                end
            catch err
                % Raise an error unless 2 output arg (errorMsg) was requested by the user
                if nargout > 1
                    errorMsg = err.message;
                else
                    rethrow(err)
                end
            end


        end

        function value = getKeyValue(app,str)
            str = regexprep(str, '.*\n\s*([^\n]+)\s*', '$1');
            if isempty(strfind(str,' REG_')), error('bad key'); end %#ok<STREMP>
            str = regexprep(str, '.*REG_\S+\s+(.*)$', '$1');
            value = str2num(str); %#ok<ST2NM>
            if isempty(value), value = str; end
        end

        function [status, msg] = setRegistryValue(app,rootKey, registryPath, key, value)
            % This function is only available on Windows platforms (no registry on Mac/Linux!)

            % Set a registry value
            % e.g. [status, msg] = setRegistryValue('HKEY_CURRENT_USER', 'Software\MyProgram', 'DataFolder', 'C:\Data')
            %      [status, msg] = setRegistryValue('HKEY_CURRENT_USER\Software\MyProgram', 'DataFolder', 'C:\Data')
            if ~ispc
                error('YMA:setRegistryValue:NotWindows','%s only works within Windows',mfilename)
            end
            % Parse the input args
            if nargin < 4
                value = key;
                key = registryPath;
                registryPath = rootKey;
            else
                registryPath = [rootKey '\' registryPath];
            end
            % Prepare the system command
            command = ['reg add ' registryPath ' /f /v ' key];
            if isnumeric(value)  % convert numeric value into a char string
                % Note: only scalar integer values can use /d !
                valueStr = num2str(value);
                if isequal(value, fix(value)) && isscalar(value)
                    command = [command ' /t REG_DWORD /d ' valueStr];
                else
                    % The ".." are only needed for non-scalar values
                    command = [command ' /t REG_SZ /d "' valueStr '"'];
                end
            else  % convert input value into a char string
                command = [command ' /t REG_SZ /d "' char(value) '"'];
            end
            % Execute the system command to update the registry (trap errors)
            [status, msg] = system(command);
            if status
                error('YMA:setRegistryValue:OpFailed',msg)
            end
        end  % setRegistryValue


        function savePlot(app,plotToSave,plotName)
            if app.ResultsAreDisplayed
                exportgraphics(plotToSave, fullfile(app.directoryPath, [plotName '-' app.SaveAsName]));
            end
        end

        function results = runanalysis(app,event,progbar)
            % Todo: validate these
            opts.dicom_dir = app.DefaultDicomDirName; %'dicom';
            opts.force_reload = app.ForceReloadCheckBox.Value; % set to true to re-run flow import and analysis
            opts.noisereduction = app.NoiseReductionCheckBox.Value; % 1 = on, 0 = off
            opts.phaseunwrapping = app.PhaseUnwrappingCheckBox.Value; % 1 = on, 0 = off

            d_path=app.directoryPath;
            ser_flow=app.serFlow;
            contour_file = char(app.ContourDropDown.Value);

            % JK Need to check that these exist
            % JK renamed these in case of lots of series indices, leading to ridiculously long filenames
            if length(ser_flow) > 4
                series_flow_hash = hash_series_flow(ser_flow);
                mat_file_pro = fullfile(d_path,['flow_pro_',series_flow_hash,'.mat']);
            else
                mat_file_pro = fullfile(d_path,['flow_pro',num2str(ser_flow),'.mat']);
            end
            mat_file_cont = fullfile(d_path,[extractBefore(app.ContourDropDown.Value,'.'),'.mat']);

            if (~isfile(mat_file_cont) || ~isfile(mat_file_pro)) && app.AnalyseFlowCheckBox.Value == 0
                app.AnalyseFlowCheckBox.Value = 1;
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Analyse flow checkbox was not ticked but a previous flow analysis could not be found.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
            end

            % analyse flow
            if app.AnalyseFlowCheckBox.Value
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Analysing flow.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                opts.ContoursonPhaseImage = app.ContoursonPhaseImage;
                [flow_data, ~, app.dicomSeriesInfo] = Flow2Mat(d_path,ser_flow,opts,app,progbar);
                parts(1).name = 'flow';
                parts(1).vars.flow = flow_data.flow;
                parts(1).vars.flow_denoised = flow_data.flow_unmasked;
                parts(1).vars.flowMag = flow_data.flow_mag;
                parts(1).vars.PCMRIMag = flow_data.mag;
                parts(1).vars.peakFlow = flow_data.peak_flow;
                parts(1).vars.pcmri = mean(flow_data.pcmri,4);
                parts(1).vars.stasis = flow_data.stasis;
                parts(1).vars.vorticity = flow_data.vorticity;
                parts(1).vars.vorticity_MAP = flow_data.vorticity_map;
                parts(1).vars.lambda2 = flow_data.lambda2;
                parts(1).CRS2SCT = flow_data.CRS2SCTflow;
                %%
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Converting contours to scanner coordinates.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                [contour_data, ~] = Contour2Mat(d_path,contour_file,flow_data.info);
                parts(1).vars.maskAtriaMin = contour_data.BWMin;

                parts(1).timesteps = flow_data.info.order.phsList/1000; %
                %         parts(1+i_cine).CRS2SCT = cine2D_data.CRS2SCTCine;
                if app.WriteFlowDataCheckBox.Value
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Writing data for flow visualisation. This may take some time.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    Data2Case([d_path,filesep,'case'],'flow',parts,app);
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Finished writing data for flow visualisation.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                end
                app.parts_saved=parts;
            end


            mf = matfile(mat_file_pro);
            mf_cont = matfile(mat_file_cont);
            if ~app.AnalyseFlowCheckBox.Value
                %TODO
                parts(1).name = 'flow';
                parts(1).vars.flow = mf.flow;
                parts(1).vars.flow_denoised = mf.flow_unmasked;
                parts(1).vars.flowMag = mf.flow_mag;
                parts(1).vars.PCMRIMag = mf.mag;
                parts(1).vars.peakFlow = mf.peak_flow;
                parts(1).vars.pcmri = mean(mf.pcmri,4);
                parts(1).vars.stasis = mf.stasis;
                parts(1).vars.vorticity =mf.vorticity;
                parts(1).vars.vorticity_MAP = mf.vorticity_map;
                parts(1).vars.lambda2 = mf.lambda2;
                parts(1).CRS2SCT  =mf.CRS2SCTflow;
                parts(1).vars.maskAtriaMin = mf_cont.BWMin;
                app.dicomSeriesInfo=mf.info;
                parts(1).timesteps = app.dicomSeriesInfo.order.phsList/1000; %
                if app.WriteFlowDataCheckBox.Value
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Writing data for flow visualisation. This may take some time.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    Data2Case([d_path,filesep,'case'],'flow',parts,app);
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Finished writing data for flow visualisation.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                end
                app.parts_saved=parts;
            end

            app.saved_mf=mf;
            app.saved_mf_cont=mf_cont;

            stasis = mf.stasis;
            peak = mf.peak_flow;
            app.vorticity_map = mf.vorticity_map;

            sz = size(mf,'flow_mag');

            CRS2SCTflow = mf.CRS2SCTflow;
            app.vox_vol = prod(sqrt(sum(CRS2SCTflow(1:3,1:3).^2)))*1e6;% m3 to ml

            if(any(cell2mat(strfind((who(mf)),'lambda2'))))
                lambda2 = reshape(mf.lambda2,[prod(sz(1:3)),sz(4)]);
            else
                lambda2 = reshape(Lambda2Calc(mf.flow_unmasked,sqrt(sum(CRS2SCTflow(1:3,1:3).^2))),[prod(sz(1:3)),sz(4)]);
            end


            flow_mag = reshape(mf.flow_mag,[prod(sz(1:3)),sz(4)]);
            BWMin_ind = find(mf_cont.BWMin);

            app.stasis_atria = stasis(BWMin_ind);  % conservative

            app.peak_atria = peak(BWMin_ind);  % conservative

            app.flow_mag_atria = flow_mag(BWMin_ind,:);

            % Calculate vortex sizes in the atria using lambda2
            lambda2(lambda2>0) = 0; % ignore all positive values
            app.lambda2_atria = lambda2(BWMin_ind,:);

            l2_threshold = mean(app.lambda2_atria(:))/2;
            l2_mask = zeros(prod(sz(1:3)),sz(4));

            vortex_sz = zeros(1,sz(4));
            for it= 1:sz(4)
                l2_mask(BWMin_ind(app.lambda2_atria(:,it)<l2_threshold),it) = 1;
                CC = bwconncomp(reshape(l2_mask(:,it),sz(1:3)));
                for ic = 1:CC.NumObjects
                    if(vortex_sz(it)<length(CC.PixelIdxList{ic}))
                        vortex_sz(it) = length(CC.PixelIdxList{ic});
                    end
                end
            end

            app.vortex_sz_ml = vortex_sz*app.vox_vol;

            info = mf.info;
            app.time_stamps = info.order.phsList/1000;
            app.vorticity_mag = reshape(mf.vorticity,[prod(sz(1:3)),sz(4)]);
            app.vorticity_mag_atria = app.vorticity_mag(BWMin_ind,:);
            app.vorticity_map_mask = app.vorticity_map(BWMin_ind);

            % TODO: Edit this to permit the printing of 'better' things
            app.stats = StatsFlow(app.stasis_atria,...
                app.peak_atria,...
                app.flow_mag_atria,...
                app.vox_vol,...
                app.time_stamps,...
                app.vorticity_mag_atria,...
                app.vortex_sz_ml,...
                app.lambda2_atria);

            % By default this is enabled, but it is a public property so
            % that a subset can be extracted without writing, if desired.
            if app.writeStatsToFile
                try
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Writing statistics to file. '); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    app.statsWriter();
                catch
                    uialert(app.OCMR4DFlowPostProcessingToolUIFigure,'An error occured writing statistics file.','An error occured');
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - An error occured writing the statistics file. '); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    if app.DebugErrorsCheckBox.Value && ~(ismcc || isdeployed), keyboard; end 
                end
            end
            app.SaveAsName = ['SavedPlot-' strjoin(strsplit(char(date),{':',' '}),'-') '.png'];

            try
                % Compute stats for the relatively intensive 3D plots
                [app.coordsX, app.coordsY, app.coordsZ]=ind2sub(size(app.parts_saved.vars.maskAtriaMin),find(app.parts_saved.vars.maskAtriaMin));
                app.delTri= (delaunayTriangulation(app.coordsX, app.coordsY, app.coordsZ));
                [app.freeB , app.PeeThree] = freeBoundary(app.delTri);
                atrialVorticity=squeeze(app.parts_saved.vars.vorticity(app.PeeThree(:,1),app.PeeThree(:,2),app.PeeThree(:,3),:));


                app.caxisMin=quantile(atrialVorticity(:),0:0.1:1); % JK this can sometimes cause a MATLAB memory failure


                %%%%%%%%%%%%%%%%%%%%%%%
                %Plot everything...
                %This "should" probably be in another method, with the bits
                %it refers to separately accessible as properties, so that
                %someone using this tool could easily run it without looking at
                %the results.
                %
                %On the other hand, the cost of displaying it all (in
                %comparison to doing the wavelet thresholding!) is pretty
                %minimal
                %%%%%%%%%%%%%%%%%%%%%%%
                app.ResultsAreDisplayed=1;
                %Again, this is by default true but one could conceivably want
                %to not plot for scripting
                if app.showAndUpdatePlots==1
                    app.FrameSlider.Limits=[1,size(parts.vars.flow_denoised,4)];
                    app.FrameSlider.MinorTicks = 1:size(parts.vars.flow_denoised,4);
                    %Atrial velocity
                    yyaxis(app.UIAxes_Vel,'left')
                    plot(app.UIAxes_Vel, app.time_stamps, mean(app.flow_mag_atria,1))
                    ylabel(app.UIAxes_Vel, 'Average velocity (m/s)');
                    xlabel(app.UIAxes_Vel, 'Time (s)');
                    title(app.UIAxes_Vel,'Mean atrial velocity/time curve')
                    yyaxis(app.UIAxes_Vel,'right')
                    plot(app.UIAxes_Vel, app.time_stamps(2:end)-app.time_stamps(1)/2,...
                        diff(squeeze(mean(app.flow_mag_atria,1))).*mean(diff(app.time_stamps)))
                    ylabel(app.UIAxes_Vel, 'Average acceleration (m s^{-2})');
                    app.formatPlots(app.UIAxes_Vel);
                    if app.SavePlots
                        app.savePlot(app.UIAxes_Vel,'Velocity-and-Acceleration');
                    end

                    %Atrial stasis histogram
                    %[hh, cc ] = hist(stasis_atria*100,10);
                    %bar([5:10:95],hh*vox_vol,1)
                    [histOut] = histogram(app.UIAxes_SH, app.stasis_atria*100,10);
                    bar(app.UIAxes_SH,5:10:95,histOut.Values*app.vox_vol,1);
                    xlabel(app.UIAxes_SH,'Stasis (percent)')
                    xlim(app.UIAxes_SH,[0 100]);
                    ylabel(app.UIAxes_SH,'Volume (ml)'); %I don't get how this is actually a volume...
                    title(app.UIAxes_SH,'Stasis Histogram');
                    app.formatPlots(app.UIAxes_SH);
                    if app.SavePlots
                        app.savePlot(app.UIAxes_SH,'Stasis-Histogram');
                    end

                    %Vortex size
                    plot(app.UIAxes_VS,app.time_stamps,app.vortex_sz_ml);
                    ylabel(app.UIAxes_VS,'Vortex size (ml)');
                    xlabel(app.UIAxes_VS,'Time (s)')
                    title(app.UIAxes_VS,'Mean vortex size vs time')
                    app.formatPlots(app.UIAxes_VS);
                    if app.SavePlots
                        app.savePlot(app.UIAxes_VS,'Vortex-size');
                    end

                    %Vorticity/time
                    integratedVorticity=mean(app.vorticity_mag_atria,1);
                    area(app.UIAxes_OmegaT,app.time_stamps,integratedVorticity);
                    ylabel(app.UIAxes_OmegaT,'Average Vorticity');
                    xlabel(app.UIAxes_OmegaT,'Time (s)')
                    title(app.UIAxes_OmegaT,'Mean vorticity over time')
                    app.integralOfVorticity=trapz(app.time_stamps,mean(app.vorticity_mag_atria,1));
                    msg=['$\int\omega$ $dt=' num2str(app.integralOfVorticity) '$'];
                    text(0,prctile(mean(app.vorticity_mag_atria,1),1),msg,'Parent',app.UIAxes_OmegaT,'Color','w','Interpreter','latex','FontSize',20);
                    app.formatPlots(app.UIAxes_OmegaT);
                    if app.SavePlots
                        app.savePlot(app.UIAxes_OmegaT,'Vorticity-Time');
                    end

                    %3D plot for a quick and very dirty look
                    updateQuiverPlot(app);
                    if app.SavePlots
                        app.savePlot(app.UIAxes_Flow3,'Flow-Visualisation');
                    end


                    %Velocity histogram
                    %Aaron's original code is commented out here
                    %[hh, vc]=hist(flow_mag_atria(:,:,:,:,:),0.05:0.1:0.55);
                    centres=0.05:0.1:0.55; %predefined magic number
                    d=diff(centres)/2;
                    edges=[centres(1)-d(1), centres(1:end-1)+d, centres(end)+d(end)];
                    edges(2:end)=edges(2:end)+eps(edges(2:end));

                    %histOut2=histogram(app.UIAxes_VH, flow_mag_atria(:,:,:,:,:),edges);
                    %hh = 100*hh/ length(BWMin_ind);
                    %hh = (100/length(BWMin_ind))*histOut2.Values;
                    %bar(app.UIAxes_VH,centres,hh);
                    % ylabel('velocity (m/s)')
                    % xlabel('time frame number')
                    % zlabel('percent atrial voxels')
                    %ylim(app.UIAxes_VH,[0 100])


                    %% I think this _actually_ does what Aaron meant:

                    for ix=1:app.dicomSeriesInfo.CardiacNumberOfImages
                        [tmpN(:, ix)]=histcounts(app.flow_mag_atria(:,ix),edges,'Normalization','probability');
                    end
                    bar3(app.UIAxes_VH, centres,tmpN*100);
                    zlabel(app.UIAxes_VH,'Proportion of voxels (%)');
                    ylabel(app.UIAxes_VH,'| Velocity | (m/s)')
                    xlabel(app.UIAxes_VH, 'Cardiac Frame')
                    title(app.UIAxes_VH,'Histograms of | velocity | over time')
                    if app.SavePlots
                        app.savePlot(app.UIAxes_VH,'Velocity-Histogram');
                    end
                end
            catch
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - MATLAB Exception: Out of memory. Ran out of memory whilst computing plots to display.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                 if app.DebugErrorsCheckBox.Value && ~(ismcc || isdeployed), keyboard; end 
            end
        end
    end

    methods (Access = public)

        function results = statsWriter(app)
            StatsFilename = ['Flow-Statistics-', getenv('username'), '-', app.StartDate, app.StatsFileExt];
            extendedStatsFilename = ['Extended-',StatsFilename];
            pathname = app.directoryPath;

            % Give MatLab write permissions
            if isfile(fullfile(pathname, StatsFilename))
                if ispc
                    fileattrib(fullfile(pathname, StatsFilename),'+w','','s')
                    fileattrib(fullfile(pathname, extendedStatsFilename),'+w','','s')
                elseif ismac
                elseif isunix
                    fileattrib(fullfile(pathname, StatsFilename),'+w','a')
                    fileattrib(fullfile(pathname, extendedStatsFilename),'+w','a')
                end
            end

            % Should check if file is open as this stops matlab writing but
            % do not know how to do this yet

            try
                DOB = app.dicomSeriesInfo.PatientBirthDate;
                formatedDOB = strjoin({DOB(7:8),DOB(5:6),DOB(1:4)},'-');
            catch
                formatedDOB = '';
            end

            FlowParameters = {'Mean flow velocity (m/s)','Mean of peak flow velocity (m/s)','AUC of mean vorticity over time (radians) (AH)','Alternate AUC of mean vorticity over time (radians) (JM)', 'Peak of mean vorticity (radians/sec)', 'Mean stasis (percent below 0.1 m/s flow threshold)', 'Mean vortex volume (ml)', 'Peak vortex volume (ml)','','Patient ID','Patient Name','Patient DOB (DD-MM-YYYY)','Series Description','Study Description','Flow IDs','','Analysis date and time','Analysis version','User'};
            Value = {app.stats.mean_flow,app.stats.peak_mean,app.stats.vorticity_int,app.stats.vorticity_int2,app.stats.vorticity_peak,app.stats.stasis_mean*100,mean(app.vortex_sz_ml,2),max(app.vortex_sz_ml,[],2),'',app.dicomSeriesInfo.PatientID,app.dicomSeriesInfo.PatientName.FamilyName,formatedDOB,app.dicomSeriesInfo.SeriesDescription,app.dicomSeriesInfo.StudyDescription,strjoin(string(app.serFlow),","),'',char(datetime),num2str(app.toolVersion),getenv('username')};
            ReportedAs = {'Mean Velocity','Peak Velocity','Vorticity','','','Global LA Stasis','LA Vortex Volume','Peak LA Vortex Volume','','','','','','','','','','',''};
            StatsTable = table(FlowParameters',Value',ReportedAs');
            StatsTable.Properties.VariableNames = {'Flow Parameters','Value','Reported in literature (Spartera et al. JCMR 2021)'};
            try
                if strcmp(app.StatsFileExt,'.xlsx')
                    writetable(StatsTable,fullfile(pathname, StatsFilename),'Sheet',extractBefore(app.ContourDropDown.Value,'.'),'writemode','overwritesheet');
                else
                    writetable(StatsTable,fullfile(pathname, StatsFilename),'Delimiter',',','writemode','overwritesheet');
                end
            catch
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Could not overwrite previous statistics file. Deleting old file and re-trying.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                delete(fullfile(pathname, StatsFilename));
                delete(fullfile(pathname, extendedStatsFilename));
                if strcmp(app.StatsFileExt,'.xlsx')
                    writetable(StatsTable,fullfile(pathname, StatsFilename),'Sheet',extractBefore(app.ContourDropDown.Value,'.'),'writemode','overwritesheet');
                else
                    writetable(StatsTable,fullfile(pathname, StatsFilename),'Delimiter',',','writemode','overwritesheet');
                end
            end

            FlowParameters = {'Mean flow velocity (m/s)','Mean of peak flow velocity (m/s)','AUC of mean vorticity over time (radians) (AH)','Alternate AUC of mean vorticity over time (radians) (JM)', 'Peak of mean vorticity (radians/sec)', 'Mean stasis (percent below 0.1 m/s flow threshold)','Median stasis (percent below 0.1 m/s flow threshold)', 'Mean vortex volume (ml)', 'Peak vortex volume (ml)','Standard deviation of stasis (percent)','Mean of peak flow (m/s)','Median of peak flow (m/s)','Pseudo-quality factor (sqrt(mean(variance(derivative(flow)))) (m/s/s)','','Patient ID','Patient Name','Patient DOB (DD-MM-YYYY)','Series Description','Study Description','Flow IDs','Approximate Sequence Duration (seconds, including reconstruction time)','','Analysis date and time','Analysis version','User'};
            Value = {app.stats.mean_flow,app.stats.peak_mean,app.stats.vorticity_int,app.stats.vorticity_int2,app.stats.vorticity_peak,app.stats.stasis_mean*100,app.stats.stasis_median*100,mean(app.vortex_sz_ml,2),max(app.vortex_sz_ml,[],2),app.stats.stasis_std, app.stats.peak_mean, app.stats.peak_median,app.stats.quality_factor_std_vel,'',app.dicomSeriesInfo.PatientID,app.dicomSeriesInfo.PatientName.FamilyName,formatedDOB,app.dicomSeriesInfo.SeriesDescription,app.dicomSeriesInfo.StudyDescription,strjoin(string(app.serFlow),","),app.total_seqtimeseconds,'',char(datetime),num2str(app.toolVersion),getenv('username')};
            ReportedAs = {'Mean Velocity','Peak Velocity','Vorticity','','','Global LA Stasis','','LA Vortex Volume','Peak LA Vortex Volume','','','','','','','','','','','','','','','',''};
            ExtStatsTable = table(FlowParameters',Value',ReportedAs');
            ExtStatsTable.Properties.VariableNames = {'Flow Parameters','Value','Reported in literature (Spartera et al. JCMR 2021)'};
            if strcmp(app.StatsFileExt,'.xlsx')
                writetable(ExtStatsTable,fullfile(pathname, extendedStatsFilename),'Sheet',extractBefore(app.ContourDropDown.Value,'.'),'writemode','overwritesheet');
            else
                writetable(ExtStatsTable,fullfile(pathname, extendedStatsFilename),'Delimiter',',','writemode','overwritesheet');
            end
            % Remove write permissions
            if ispc
                fileattrib(fullfile(pathname, StatsFilename),'-w','','s')
                fileattrib(fullfile(pathname, extendedStatsFilename),'-w','','s')
            elseif ismac
            elseif isunix
                fileattrib(fullfile(pathname, StatsFilename),'-w','a')
                fileattrib(fullfile(pathname, extendedStatsFilename),'-w','a')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Custom resize
            currentTabGroupSize = app.TabGroup.Position;
            app.OCMR4DFlowPostProcessingToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            %Put text into plots:
            xlabel(app.UIAxes_Vel,'');
            ylabel(app.UIAxes_Vel,'');
            app.OCMR4DFlowPostProcessingToolUIFigure.Resize = 'on';
            app.OCMR4DFlowPostProcessingToolUIFigure.Clipping = 'off';
            title(app.UIAxes_Vel,'Results will be available after processing!')
            app.TabGroup.Position = currentTabGroupSize;
            app.TabGroup.SelectedTab = app.InstructionsTab;
            app.DialogBoxTextArea.Value =  char(string(datetime('now','Format','HH:mm')) + string([' - OCMR 4D Flow Post-Processing Tool. Version ', num2str(app.toolVersion),'. ']) + string(app.DialogBoxTextArea.Value));
            app.maxStreamlinesToPlot = app.MaxStreamlinestoPlotEditField.Value;
            app.showMagnitudeOnVisualisation = app.ShowSlicesCheckBox.Value;
            app.SliceAlphaValue = app.ImageTransparencyEditField.Value;

            if (isdeployed || ismcc) 
                app.DebugErrorsCheckBox.Visible='off';
            end


            %Check to see if preferences exist and load them if so
            %Set OS-specific sane default permissions -- the order below
            %matters!
            if ismac
                app.PreferencesLocation = "~/Library/Preferences/AtrialAnalysis_Preferences.json";
            elseif isunix
                app.PreferencesLocation = "~/.config/AtrialAnalysis_Preferences.json";
            elseif ispc % JK changed from iswin
                app.PreferencesLocation = "%AppData%\AtrialAnalysis_Preferences.json";
            else
                error('Unknown OS');
            end

            %If it exists, load preferences from it
            if(isfile(app.PreferencesLocation))
                try
                    jsonString = fileread(app.PreferencesLocation);
                    savedStruct = jsondecode(jsonString);
                    app.DefaultDicomDirName = savedStruct.DefaultDicomDirName;
                    app.DicomFolderEditField.Value = app.DefaultDicomDirName; 

                    %app.ContourDropDown.Items = savedStruct.Contour_Filename;
                    %app.ContourDropDown.Value = savedStruct.Contour_Filename;
                    
                    app.SliceAlphaValue = savedStruct.SliceAlphaValue;
                    app.ImageTransparencyEditField.Value = app.SliceAlphaValue;

                    app.SavePlots=savedStruct.SavePlots;
                    app.SaveAllPlotsCheckBox.Value = app.SavePlots; 

                    app.plotType = savedStruct.plotType;
                                                           
                    app.PlotSelector.Value = savedStruct.PlotSelectorValue;
                    app.PlotSelectorValueChanged(); 
                catch ME
                    warning('Json decoding failed: exception raised is:')
                    disp(getReport(ME,'extended','hyperlinks','on'));
                    if app.DebugErrorsCheckBox.Value && ~(ismcc || isdeployed), keyboard; end 
                end

            end
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtons(app, event)
            app.GoButton.Text = 'Loading Dicoms...'; app.GoButton.Enable = 'off'; app.Lamp.Color = [1 0.5 0];
            app.directoryPath=uigetdir(); figure(app.OCMR4DFlowPostProcessingToolUIFigure);
            if app.directoryPath ~= 0
                if ispc
                    app.OpenCurrentDirectoryButton.Enable = 'on';
                end

                % If group, select first for now (will loop during run)
                d = dir(app.directoryPath);
                d(~[d.isdir])= []; % Remove all non directories.
                if app.GroupCheckBox.Value
                    app.ListofStudiestoAnalyseListBox.Items = setdiff({d.name},{'.','..','DICOM'}); % remove these

                    % Not a group directory!
                    if isempty(app.ListofStudiestoAnalyseListBox.Items)
                        app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - ERROR! Group tickbox checked but a non-group directory was selected.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                        uialert(app.OCMR4DFlowPostProcessingToolUIFigure,'Group tickbox checked but a non-group directory was selected.','Try again.','Icon','error');
                    end

                    app.ListofStudiestoAnalyseListBox.ItemsData = app.ListofStudiestoAnalyseListBox.Items;
                    app.ListofStudiestoAnalyseListBox.Value = app.ListofStudiestoAnalyseListBox.Items; % Initially select all
                    app.groupDirectoryPath = app.directoryPath;
                    app.directoryPath = fullfile(app.groupDirectoryPath,app.ListofStudiestoAnalyseListBox.Items{1}); % Select first for now
                end

                app = GetListOfContours(app,1);

                % Enable other options not directory has been selected
                app.ContourDropDown.Enable = 'on';
                app.ContourDropDownLabel.Enable = 'on';
                app.CurrentDirectoryEditField.Enable = 'on';
                app.CurrentDirectoryEditFieldLabel.Enable = 'on';
                app.SeriesIDsEditField.Enable = 'on';
                app.SeriesIDsEditFieldLabel.Enable = 'on';
                app.ListBox.Enable = 'on';

                app = finddicoms(app);

                app.GoButton.Enable = 'on';
                app.GoButton.Text = 'Go!';
                app.Lamp.Color = [1 1 1];
            else % user closed uigetdir
                app.GoButton.Text = 'Go!'; app.GoButton.Enable = 'off'; app.Lamp.Color = [1 1 1];
            end

        end

        % Callback function
        function SetDefaultStudyNamesandNumbersMenuSelected(app, event)
            %Show preference box
            app.PreferencesTab.HandleVisibility = "on";
        end

        % Value changed function: DicomFolderEditField
        function DicomFolderEditFieldValueChanged(app, event)
            value = app.DicomFolderEditField.Value;
            app.DefaultDicomDirName = value;
        end

        % Value changed function: SeriesIDsEditField
        function SeriesIDsEditFieldValueChanged(app, event)
            value = app.SeriesIDsEditField.Value;
            app.GoButton.Enable = 'on';
            app.GoButton.Text = 'Go!';
            app.Lamp.Color = [1 1 1];

            app.SeriesIDsEditField.FontColor = [0 0 0];

            if(isempty(str2double(value)))
                app.SeriesIDsEditField.FontColor='r';
            else
                app.serFlow = str2num(value); % JK changed str2double to str2num otherwise manually changing default flow series gave error
                app.SeriesIDsEditField.FontColor='k';
            end
            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Series flow IDs manually entered.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

            app.DICOMStudyEditField.Editable = 'on'; % If dicom series are manually entered, allow study number to be manually entered aswell. This isn't ideal.

            dicomData = processDicomDirRecursive(app,app.directoryPath,'*');
            app = IdentifyDicomSeries(app,dicomData); % Update highlighted series
        end

        % Callback function
        function ContourNameEditFieldValueChanged(app, event)

        end

        % Value changed function: CurrentDirectoryEditField
        function CurrentDirectoryEditFieldValueChanged(app, event)
            app.directoryPath = app.CurrentDirectoryEditField.Value;
            [~,app.CurrentDirectoryEditField.Value] = fileparts(app.directoryPath);
            app.CurrentDirectoryEditField.Tooltip = app.directoryPath;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.ListBox.Enable = 'off'; % Disable whilst active
            if strcmp(app.ContourDropDown.Value,'-Analyse all of above-')
                Analyse_All_Contours_In_Dataset = 1;
            else
                Analyse_All_Contours_In_Dataset = 0;
            end

            app.FailedLabel.Visible = 0;
            app.StartDate = strjoin(strsplit(char(date),{':',' '}));
            app.TabGroup.SelectedTab = app.PreferencesTab;

            % Don't start if there is an Excel application open, as this
            % may interfere with writing statistics later
            if ispc % Only works for PC
                try
                    %Check if an Excel server is running
                    excel = actxGetRunningServer('Excel.Application');
                    answer = uiconfirm(app.OCMR4DFlowPostProcessingToolUIFigure,'Please close any open Excel spreadsheets before continuing.','Please close Excel.','Icon','warning','Options','Ok');
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Please close any open Excel spreadsheets before continuing.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    release(excel);
                catch
                    % This will fail if there is no excel application open
                    % hence catch
                end
            else
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Please close any open Excel spreadsheets before continuing.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
            end

            app.GoButton.Enable = 'off';
            app.Lamp.Color = [1 0.5 0]; % Lamp to indicate processing
            app.FailedLabel.Visible = 0; app.FailedDatasets = {};
            app.GoButton.Text = 'Analysing...'; pause(0.1)
            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Analysis started.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

            if app.GroupCheckBox.Value || Analyse_All_Contours_In_Dataset % JK added if true, loop over studies and contours
                % First find out how many datasets there are to analyse
                Total_DatasetsN = 0;
                for LoopN = 1:size(app.ListofStudiestoAnalyseListBox.Value,2)
                    if app.GroupCheckBox.Value
                        app.directoryPath = fullfile(app.groupDirectoryPath,app.ListofStudiestoAnalyseListBox.Value{LoopN});
                        [~,app.CurrentDirectoryEditField.Value] = fileparts(app.directoryPath);
                        app.CurrentDirectoryEditField.Tooltip = app.directoryPath;
                    else
                        app.groupDirectoryPath = app.directoryPath;
                    end
                    app = GetListOfContours(app,0);
                    Total_DatasetsN = Total_DatasetsN + size(app.ContourDropDown.Items,2);
                end

                progbar = uiprogressdlg(app.OCMR4DFlowPostProcessingToolUIFigure,'Title','Please wait analysis is running','Cancelable','on');drawnow
                progbar.Message = ['Estimated Time Remaining: ',sec2hms(5*60*Total_DatasetsN),'.']; % Very rough estimate (will become more accurate after a few datasets are ran)
                timeStart = tic;
                Failed_DatasetsN = 0; Total_DatasetsRanN = 0; app.FailedDatasets = {};
                for LoopN = 1:size(app.ListofStudiestoAnalyseListBox.Value,2)

                    if app.GroupCheckBox.Value
                        app.directoryPath = fullfile(app.groupDirectoryPath,app.ListofStudiestoAnalyseListBox.Value{LoopN});
                        [~,app.CurrentDirectoryEditField.Value] = fileparts(app.directoryPath);
                        app.CurrentDirectoryEditField.Tooltip = app.directoryPath;
                    else
                        app.groupDirectoryPath = app.directoryPath;
                    end

                    app = GetListOfContours(app,0);

                    for ContourN = 1:size(app.ContourDropDown.Items,2)
                        app.GoButton.Text = ['Analysing Dataset: ',num2str(LoopN),'/',num2str(size(app.ListofStudiestoAnalyseListBox.Value,2)),newline,'Contour: ',num2str(ContourN),'/',num2str(size(app.ContourDropDown.Items,2))];

                        app.ContourDropDown.Value = app.ContourDropDown.Items{ContourN};
                        app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + [' - Now processing dataset: ',app.CurrentDirectoryEditField.Value,', contour: ',app.ContourDropDown.Value,'.']); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                        Total_DatasetsRanN = Total_DatasetsRanN + 1;

                        if progbar.CancelRequested
                            break
                        end

                        app.ForceReloadCheckBox.Value = 1; % Force this to be on for analysing multiple studies
                        app.AnalyseFlowCheckBox.Value = 1; % Force this to be on for analysing multiple studies

                        % Attempt analysis
                        try
                            app = finddicoms(app); % Update Flow series ID and identify dicoms
                            if progbar.CancelRequested
                                break
                            end
                            runanalysis(app,event,progbar);
                        catch
                            Failed_DatasetsN = Failed_DatasetsN + 1;
                            disp(['Dataset: ',app.ListofStudiestoAnalyseListBox.Value{LoopN},', Contour: ',app.ContourDropDown.Items{ContourN},' skipped due to error. Flow series IDs: ','[',num2str(app.serFlow(1)),',',num2str(app.serFlow(2)),',',num2str(app.serFlow(3)),',',num2str(app.serFlow(4)),']',', re-run manually.'])
                            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ['- Dataset: ',app.ListofStudiestoAnalyseListBox.Value{LoopN},', Contour: ',app.ContourDropDown.Items{ContourN},' skipped due to error. Flow series IDs: ','[',num2str(app.serFlow(1)),',',num2str(app.serFlow(2)),',',num2str(app.serFlow(3)),',',num2str(app.serFlow(4)),']',', re-run manually.']); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                            app.FailedLabel.Visible = 1; app.FailedDatasets{Failed_DatasetsN} = [app.CurrentDirectoryEditField.Value,' ',app.ContourDropDown.Value];
                            app.FailedLabel.Text = [num2str(Failed_DatasetsN),'/',num2str(Total_DatasetsN),' Failed']; pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

                            % Write failed dicoms to file
                            fid = fopen(fullfile(app.groupDirectoryPath, 'ListOfFailed.csv'),"w");
                            if fid == -1
                                error('File opening %s failed!',fullfile(app.groupDirectoryPath, 'ListOfFailed.csv'));
                            else
                                fprintf(fid, "List of failed datasets\n");
                                for Failed_n = 1:Failed_DatasetsN
                                    fprintf(fid,strcat(string(app.FailedDatasets{Failed_n}),'\n'));
                                end
                                fclose(fid);
                            end
                        end
                        % Update progress bar with estimate time remaining
                        progbar.Value = (Total_DatasetsRanN /Total_DatasetsN);
                        time_remaining = (toc(timeStart)./Total_DatasetsRanN)*(Total_DatasetsN-Total_DatasetsRanN);
                        progbar.Message = ['Estimated Time Remaining: ',sec2hms(time_remaining),'.'];
                    end
                end
                app.Lamp.Color = [0 1 0];
                app.GoButton.Text = 'Done!';
                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Analysis has finished.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
            else % single dataset and single contour analysis
                try
                    progbar = uiprogressdlg(app.OCMR4DFlowPostProcessingToolUIFigure,'Title','Please wait analysis is running','Indeterminate','on');drawnow
                    runanalysis(app,event,progbar);
                    app.Lamp.Color = [0 1 0];
                    app.GoButton.Text = 'Done!';
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Analysis has finished. You can view plots by clicking on the appropriate tab.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

                catch ME
                    app.GoButton.Text = 'Failed!';
                    app.Lamp.Color = [1 0 0];
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = ...
                        char(string(datetime('now','Format','HH:mm')) + ' - An error occured! Analysis has failed. '); 
                    
                    pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

                    
                    if app.DebugErrorsCheckBox.Value && ~(ismcc || isdeployed), disp(getReport(ME)); app.GoButton.Enable='on';  keyboard; end 
                end
            end
            close(progbar) % close the progress bar
            app.ListBox.Enable = 'on'; % Disable whilst active
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx);
            app.currentCardiacFrame=round(value);
            app.ThreeDeeCameraPositions{1}=app.UIAxes_Flow3.CameraPosition;
            app.ThreeDeeCameraPositions{2}=app.UIAxes_Flow3.Position;
            if app.ResultsAreDisplayed
                app.updateQuiverPlot();
            end
        end

        % Value changed function: MaxStreamlinestoPlotEditField
        function MaxStreamlinestoPlotEditFieldValueChanged(app, event)
            value = app.MaxStreamlinestoPlotEditField.Value;
            app.maxStreamlinesToPlot = value;
            if app.ResultsAreDisplayed
                updateQuiverPlot(app);
            end
        end

        % Value changed function: ShowSlicesCheckBox
        function ShowSlicesCheckBoxValueChanged(app, event)
            value = app.ShowSlicesCheckBox.Value;
            app.showMagnitudeOnVisualisation=value;
        end

        % Value changed function: ImageTransparencyEditField
        function ImageTransparencyEditFieldValueChanged(app, event)
            value = app.ImageTransparencyEditField.Value;
            app.SliceAlphaValue = value;
            if app.ResultsAreDisplayed
                updateQuiverPlot(app);
            end
        end

        % Close request function: 
        % OCMR4DFlowPostProcessingToolUIFigure
        function OCMR4DFlowPostProcessingToolUIFigureCloseRequest(app, event)
            %Save preferences
            savingStruct.DefaultDicomDirName = app.DefaultDicomDirName ;
            savingStruct.Contour_Filename = app.ContourDropDown.Value;
            savingStruct.SliceAlphaValue = app.SliceAlphaValue;
            savingStruct.PreferencesLocation = app.PreferencesLocation;
            savingStruct.SavePlots = app.SavePlots;
            savingStruct.plotType = app.plotType;
            savingStruct.PlotSelectorValue = app.PlotSelector.Value;
            textToWrite = jsonencode(savingStruct);
            fid = fopen(app.PreferencesLocation, 'w');
            if fid ~= -1
                fprintf(fid,"%s",textToWrite);
                fclose(fid);
            end % silently do nothing...
            delete(app) % bye bye

        end

        % Value changed function: SaveAllPlotsCheckBox
        function SaveAllPlotsCheckBoxValueChanged(app, event)
            value = app.SaveAllPlotsCheckBox.Value;
            app.SavePlots=value;
        end

        % Value changed function: PlotSelector
        function PlotSelectorValueChanged(app, event)
            value = app.PlotSelector.Value;
            app.plotType=value;
            if ~isempty(app.cbH)
                app.cbH.delete();
            end
            reset(app.UIAxes_Flow3);
            if strcmp(value,"Vorticity Contours")
                app.SliceSpinner.Enable=1;
                app.SliceSpinner.Visible = 1;
                app.SliceSpinnerLabel.Visible = 1;
                app.SliceSpinnerLabel.Enable=1;
            else
                app.SliceSpinner.Enable = 0;
                app.SliceSpinner.Visible = 0;
                app.SliceSpinnerLabel.Visible = 0;
                app.SliceSpinnerLabel.Enable=0;
            end
            if app.ResultsAreDisplayed
                app.updateQuiverPlot();
            end
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            value = app.SliceSpinner.Value;
            app.currentSlice=value;
            app.updateQuiverPlot();
        end

        % Key press function: OCMR4DFlowPostProcessingToolUIFigure
        function OCMR4DFlowPostProcessingToolUIFigureKeyPress(app, event)
            key = event.Key;
            if app.ResultsAreDisplayed
                switch key
                    case 'uparrow'
                        if app.currentSlice < app.FrameSlider.Limits(2)
                            app.currentSlice = app.currentSlice+1;
                            app.SliceSpinner.Value = app.currentSlice;
                            app.updateQuiverPlot();
                        end
                    case 'downarrow'
                        if app.currentSlice > app.FrameSlider.Limits(1)
                            app.currentSlice = app.currentSlice-1;
                            app.SliceSpinner.Value = app.currentSlice;
                            app.updateQuiverPlot();
                        end
                    case 'leftarrow'
                        if app.currentCardiacFrame >1
                            app.currentCardiacFrame = app.currentCardiacFrame -1;
                            app.FrameSlider.Value = app.currentCardiacFrame;
                            app.updateQuiverPlot();
                        end
                    case 'rightarrow'
                        if app.currentCardiacFrame <  app.numCardiacFrames
                            app.currentCardiacFrame = app.currentCardiacFrame +1;
                            app.FrameSlider.Value = app.currentCardiacFrame;
                            app.updateQuiverPlot();
                        end
                end
            end
        end

        % Value changed function: GroupCheckBox
        function GroupCheckBoxValueChanged(app, event)
            value = app.GroupCheckBox.Value;

            app.ListofStudiestoAnalyseListBox.Enable = value;
            app.ListofStudiestoAnalyseListBoxLabel.Enable = value;
            app.GenerateStatsFileFromSelectedButton.Enable = value;
            app.SelectAllButton.Enable = value;
            app.SelectNoneButton.Enable = value;
        end

        % Value changed function: IntegrateoverframesCheckBox
        function IntegrateoverframesCheckBoxValueChanged(app, event)
            value = app.IntegrateoverframesCheckBox.Value;
            if app.IntegrateoverframesCheckBox.Value
                app.FrameSlider.Visible = 0;
            else
                app.FrameSlider.Visible = 1;
            end
            if app.ResultsAreDisplayed
                app.updateQuiverPlot();
            end
        end

        % Button pushed function: SaveVelocityPlotButton
        function SaveVelocityTimePlot(app, event)
            app.savePlot(app.UIAxes_Vel,'Velocity-and-Acceleration');
        end

        % Button pushed function: SaveVelocityPlotButton_2
        function SaveVorticityTimePlot(app, event)
            app.savePlot(app.UIAxes_OmegaT,'Vorticity-Time');
        end

        % Button pushed function: SaveVelocityPlotButton_3
        function SaveVortexSizePlot(app, event)
            app.savePlot(app.UIAxes_VS,'Vortex-size');
        end

        % Button pushed function: SaveVelocityPlotButton_4
        function SaveStasisHistoPlot(app, event)
            app.savePlot(app.UIAxes_SH,'Stasis-Histogram');
        end

        % Button pushed function: SaveVelocityPlotButton_5
        function SaveVelocityHistoPlot(app, event)
            app.savePlot(app.UIAxes_VH,'Velocity-Histogram');
        end

        % Button pushed function: SaveVelocityPlotButton_6
        function SaveFlowVisPlot(app, event)
            app.savePlot(app.UIAxes_Flow3,'Flow-Visualisation');
        end

        % Value changed function: WriteFlowDataCheckBox
        function WriteFlowCheckbox(app, event)
            value = app.WriteFlowDataCheckBox.Value;
            app.GoButton.Enable = 'on';
            app.GoButton.Text = 'Go!';
        end

        % Button pushed function: 
        % GenerateStatsFileFromSelectedButton
        function GenerateStatsFileFromSelectedButtonPushed(app, event)
            % JK first loop over all studies and find all possible contour
            % names
            ListofSheets = [];
            for LoopN = 1:size(app.ListofStudiestoAnalyseListBox.Value,2)
                StatsFiledirPath = fullfile(app.groupDirectoryPath,app.ListofStudiestoAnalyseListBox.Value{LoopN});
                d = dir(StatsFiledirPath);
                d = d(contains({d.name},{'Extended'}));
                [~, index]   = max([d.datenum]); % find index of newest file incase there are multiple
                CurrentStatsFileName = fullfile(StatsFiledirPath,d(index).name);
                ListofSheets = cat(1,ListofSheets,sheetnames(CurrentStatsFileName));
            end
            ListofSheets = unique(ListofSheets);

            % JK loop over selected studies, find most recent stats file,
            % get data and concatenate into a single stats file
            for SheetN = 1:size(ListofSheets,1)


                app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Writing collated statistics file to the group directory.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                for LoopN = 1:size(app.ListofStudiestoAnalyseListBox.Value,2)
                    StatsFiledirPath = fullfile(app.groupDirectoryPath,app.ListofStudiestoAnalyseListBox.Value{LoopN});
                    d = dir(StatsFiledirPath);
                    d = d(contains({d.name},{'Extended'}));
                    if ~isempty(d) % If stats file exists
                        [~, index]   = max([d.datenum]); % find index of newest file incase there are multiple
                        if size(d,1) > 1
                            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + [' - Multiple versions of statistics file for study "', app.ListofStudiestoAnalyseListBox.Value{LoopN},'" were found. Selecting the newest version.']); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                        end
                        CurrentStatsFileName = fullfile(StatsFiledirPath,d(index).name);

                        opts = detectImportOptions(CurrentStatsFileName);
                        opts.VariableTypes = ["char","char","char"];

                        % Check sheet name is in dataset
                        if any(ListofSheets(SheetN) == sheetnames(CurrentStatsFileName))

                            if ~exist('CollStatsTable','var')
                                opts.SelectedVariableNames = ["FlowParameters","Value"];
                                if strcmp(app.StatsFileExt,'.xlsx')
                                    opts.Sheet = ListofSheets(SheetN);
                                    CollStatsTable = readtable(CurrentStatsFileName,opts);
                                else
                                    opts.Delimiter = ',';
                                    CollStatsTable = readtable(CurrentStatsFileName,opts);
                                end
                            else
                                opts.SelectedVariableNames = "Value";
                                if strcmp(app.StatsFileExt,'.xlsx')
                                    opts.Sheet = ListofSheets(SheetN);
                                    CollStatsTable(:,LoopN+1) = readtable(CurrentStatsFileName,opts);
                                else
                                    opts.Delimiter = ',';
                                    CollStatsTable(:,LoopN+1) = readtable(CurrentStatsFileName,opts);
                                end
                            end
                        else
                            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + [' - Dataset: ',app.ListofStudiestoAnalyseListBox.Value{LoopN},' is missing contour: ',char(ListofSheets(SheetN))]); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

                            % Sheet not in dataset, fill with empty array
                            % If CollStatsTable doesn't exist yet,
                            % find the size from a sheet that does exist in the
                            % dataset
                            if ~exist('CollStatsTable','var')
                                opts.SelectedVariableNames = ["FlowParameters","Value"];
                                if strcmp(app.StatsFileExt,'.xlsx')
                                    list = sheetnames(CurrentStatsFileName);
                                    opts.Sheet = list(1);
                                    CollStatsTable = readtable(CurrentStatsFileName,opts);
                                else
                                    opts.Delimiter = ',';
                                    CollStatsTable = readtable(CurrentStatsFileName,opts);
                                end
                                CollStatsTable.Value = zeros(1,size(CollStatsTable,1))'; % Remove values as sheet doesn't exist
                            else
                                CollStatsTable(:,LoopN+1) = table(zeros(1,size(CollStatsTable,1))');
                            end
                        end
                    else
                        uialert(app.OCMR4DFlowPostProcessingToolUIFigure,...
                            ['Cannot find statistics file for study "',app.ListofStudiestoAnalyseListBox.Value{LoopN},...
                            '". Skipping.'],'An error occured');
                        app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = ...
                            char(string(datetime('now','Format','HH:mm')) + ...
                            [' - Cannot find statistics file for study "',app.ListofStudiestoAnalyseListBox.ItemsData{LoopN},'". Skipping.']); ...
                            pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    end
                end

                % Write final stats file to group directory
                CollStatsFilename = ['Collated-Flow-Statistics-', getenv('username'), '-', app.StartDate, app.StatsFileExt];

                % Give MatLab write permissions
                if isfile(fullfile(app.groupDirectoryPath, CollStatsFilename))
                    if ispc
                        fileattrib(fullfile(app.groupDirectoryPath, CollStatsFilename),'+w','','s')
                    elseif ismac
                    elseif isunix
                        fileattrib(fullfile(app.groupDirectoryPath, CollStatsFilename),'+w','a')
                    end
                end

                try
                    if strcmp(app.StatsFileExt,'.xlsx')
                        writetable(CollStatsTable,fullfile(app.groupDirectoryPath, CollStatsFilename),'Sheet',ListofSheets(SheetN),'writemode','overwritesheet');
                    else
                        writetable(CollStatsTable,fullfile(app.groupDirectoryPath, CollStatsFilename),'Delimiter',',','writemode','overwritesheet');
                    end
                catch
                    app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Could not overwrite previous statistics file. Deleting old file and re-trying.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
                    delete(fullfile(app.groupDirectoryPath, CollStatsFilename));
                    if strcmp(app.StatsFileExt,'.xlsx')
                        writetable(CollStatsTable,fullfile(app.groupDirectoryPath, CollStatsFilename),'Sheet',ListofSheets(SheetN),'writemode','overwritesheet');
                    else
                        writetable(CollStatsTable,fullfile(app.groupDirectoryPath, CollStatsFilename),'Delimiter',',','writemode','overwritesheet');
                    end
                end

                % Remove write permissions
                if ispc
                    fileattrib(fullfile(app.groupDirectoryPath, CollStatsFilename),'-w','','s')
                elseif ismac
                elseif isunix
                    fileattrib(fullfile(app.groupDirectoryPath, CollStatsFilename),'-w','a')
                end
                clearvars CollStatsTable
            end
            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Finished writing collated statistics file to the group directory.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            ItemsData = app.ListofStudiestoAnalyseListBox.Items;
            app.ListofStudiestoAnalyseListBox.ItemsData = ItemsData;
            app.ListofStudiestoAnalyseListBox.Value = ItemsData;

        end

        % Button pushed function: SelectNoneButton
        function SelectNoneButtonPushed(app, event)
            app.ListofStudiestoAnalyseListBox.Value = {};
        end

        % Value changed function: StatisticsFileExtDropDown
        function StatsFileExtensionDropDownValueChanged(app, event)
            value = app.StatisticsFileExtDropDown.Value;
            app.StatsFileExt = value;
        end

        % Callback function
        function InstructionsTabSizeChanged(app, event)
            position = app.InstructionsTab.Position;
        end

        % Button down function: InstructionsTab
        function InstructionsTabButtonDown(app, event)
            app.OCMR4DFlowPostProcessingToolboxVersion20InstructionsLabel.Text = ['OCMR 4D Flow Post-Processing Toolbox Version ',num2str(app.toolVersion),' Instructions'];
            expand(app.Tree,'all')
        end

        % Value changed function: ListofStudiestoAnalyseListBox
        function ListofStudiestoAnalyseListBoxValueChanged(app, event)
            value = app.ListofStudiestoAnalyseListBox.Value;
        end

        % Value changed function: ForceReloadCheckBox
        function ForceReloadCheckBoxValueChanged(app, event)
            value = app.ForceReloadCheckBox.Value;
            app.GoButton.Enable = 'on';
            app.GoButton.Text = 'Go!';
        end

        % Button pushed function: WriteMovieButton
        function CreateMovie(app, event)
            % make moive and read in some

            dirname = app.directoryPath;
            ser_ind = 41;

            [img, ~] = Load3DDicom(data_dir,data_series,opt,app);

            img_show = abs(squeeze(img));
            img_show = img_show(130:230,70:180,:); % 72 131  194 236
            NPHS = size(img_show,3);
            img_ft = fft(img_show,[],3);
            %img_ft = bsxfun(@times,img_ft,reshape((1-gausswin(NPHS,7)),1,1,NPHS));
            img_ft = bsxfun(@times,img_ft,reshape((1-chebwin(NPHS,1000)),1,1,NPHS));
            img_show = abs(ifft(img_ft,[],3));
            scale = [50 900];         % intensity scale
            fps   = app.MovieFPSSpinner.Value; % movie frame rate (frames per sec)

            % Render out movie frames
            clear mv; frameCounter = 1; % Re-initalize
            figure(1)
            subplot('position', [0 0 1 1])
            set(gcf, 'color', 'black');
            for ii = 1:NPHS

                imshow(imresize(img_show(:,:,ii),8),scale,'border','tight');
                axis image; axis off; colormap gray;
                mv(frameCounter) = getframe(gcf);
                frameCounter = frameCounter + 1;
            end

            % Save to AVI file
            writerObj = VideoWriter(app.MovieFilenameEditField.Value,'MPEG-4');
            writerObj.FrameRate = fps;
            writerObj.Quality = 100;
            % writerObj.profile = 'MPEG-4';
            open(writerObj);
            writeVideo(writerObj,mv);
            close(writerObj);

        end

        % Value changed function: MovieFPSSpinner
        function EditMovieFPS(app, event)
            value = app.MovieFPSSpinner.Value;
        end

        % Value changed function: MovieFilenameEditField
        function EditMovieName(app, event)
            value = app.MovieFilenameEditField.Value;
        end

        % Button pushed function: OpenCurrentDirectoryButton
        function OpenDirectory(app, event)
            if ispc
                winopen(app.directoryPath);
            end
        end

        % Value changed function: ContourDropDown
        function ContourDropDownFieldChanged(app, event)
            if ~strcmp(app.ContourDropDown.Value,'-Analyse all of above-')
                value = app.ContourDropDown.Value;
                app.ContourDropDown.Value=value;
                app = finddicoms(app); % Reload series IDs when contour filename changed
            end
            app.GoButton.Enable = 'on';
            app.GoButton.Text = 'Go!';
            app.Lamp.Color = [1 1 1];
        end

        % Button pushed function: ClearLogButton
        function ClearDialogBox(app, event)
            app.DialogBoxTextArea.Value =  char(string(datetime('now','Format','HH:mm')) + string([' - OCMR 4D Flow Post-Processing Tool. Version ', num2str(app.toolVersion),'. Select a dataset to begin.']));
        end

        % Button pushed function: ClearDicomCacheButton
        function ClearDicomCacheButtonPushed(app, event)
            % Function processDicomDir saves the following cache file:
            % cacheFile = fullfile(tempdir(),['processDicomDir_CACHE_' dirHash(strDir, strWildcard) '.mat']);
            % Hence, if we want to clear them, we need to find all in
            % tempdir
            recycle on
            temp_dir = tempdir();
            list = dir(temp_dir);
            deleted_total = 0;
            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Deleting Dicom Cache Files - Please Wait. '); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
            for n = 1:length(list)
                if contains(list(n).name,'processDicomDir_CACHE_')
                    % remove cache file
                    delete(fullfile(temp_dir,list(n).name))
                    deleted_total = deleted_total + 1;
                end
            end
            recycle off
            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + [' - ',num2str(deleted_total),' Dicom Cache Files Deleted. ']); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');
        end

        % Value changed function: DICOMStudyEditField
        function DICOMStudyEditFieldValueChanged(app, event)
            value = app.DICOMStudyEditField.Value;
            app.StudyNumber = value;
            app = IdentifyDicomSeries(app); % Update highlighted series
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.ListBox.Enable = 'off'; % Disable whilst active
            value = app.ListBox.Value;

            app.GoButton.Enable = 'on';
            app.GoButton.Text = 'Go!';
            app.Lamp.Color = [1 1 1];

            app.SeriesIDsEditField.FontColor = [0 0 0];

            if ~isempty(app.ListBox.Value)
                for value_n = 1:size(app.ListBox.Value,2)
                    series_numbers(value_n) = sscanf(string(app.ListBox.Value(value_n)),'%d');
                end
            end

            app.serFlow = series_numbers;

            Series_String = sprintf('%.0f,' , series_numbers);
            Series_String = ['[',Series_String(1:end-1),']']; % strip final comma, encase in square brackets
            app.SeriesIDsEditField.Value = Series_String; % Update edit field

            app.DialogBoxTextArea.Value{length(app.DialogBoxTextArea.Value)+1} = char(string(datetime('now','Format','HH:mm')) + ' - Series flow IDs manually selected.'); pause(0.1); scroll(app.DialogBoxTextArea, 'bottom');

            app.DICOMStudyEditField.Editable = 'on'; % If dicom series are manually entered, allow study number to be manually entered aswell. This isn't ideal.

            dicomData = processDicomDirRecursive(app,app.directoryPath,'*');
            app = IdentifyDicomSeries(app,dicomData); % Update highlighted series
            app.ListBox.Enable = 'on'; % Disable whilst active
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OCMR4DFlowPostProcessingToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {544, 544};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OCMR4DFlowPostProcessingToolUIFigure = uifigure('Visible', 'off');
            app.OCMR4DFlowPostProcessingToolUIFigure.Name = 'OCMR 4D Flow Post-Processing Tool';
            app.OCMR4DFlowPostProcessingToolUIFigure.Icon = 'OCMRlogo.png';
            app.OCMR4DFlowPostProcessingToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @OCMR4DFlowPostProcessingToolUIFigureCloseRequest, true);
            app.OCMR4DFlowPostProcessingToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.OCMR4DFlowPostProcessingToolUIFigure.KeyPressFcn = createCallbackFcn(app, @OCMR4DFlowPostProcessingToolUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.OCMR4DFlowPostProcessingToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MainSettingsPanel = uipanel(app.LeftPanel);
            app.MainSettingsPanel.Tooltip = {'The main settings are found here.'};
            app.MainSettingsPanel.Title = 'Main Settings';
            app.SeriesIDsEditField = uieditfield(app.MainSettingsPanel, 'text');
            app.SeriesIDsEditField.ValueChangedFcn = createCallbackFcn(app, @SeriesIDsEditFieldValueChanged, true);
            app.SeriesIDsEditField.Enable = 'off';
            app.SeriesIDsEditField.Tooltip = {'First series ID should be a magnitude image, the next three should be the phase series IDs. [M,P,P,P]'};
            app.SeriesIDsEditField.Value = '[0,0,0,0]';
            app.SeriesIDsEditFieldLabel = uilabel(app.MainSettingsPanel);
            app.SeriesIDsEditFieldLabel.Enable = 'off';
            app.SeriesIDsEditFieldLabel.Text = 'Series IDs:';
            app.SelectDirectoryButton = uibutton(app.MainSettingsPanel, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtons, true);
            app.SelectDirectoryButton.Icon = 'foldericon.png';
            app.SelectDirectoryButton.Tooltip = {'Select a directory that: '; '(a) Contains a folder containing flow dicoms, named by default "dicom" (but changable in the preferences tab); and '; '(b) is writable by your user, as results will be placed here.'};
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.ContourDropDownLabel = uilabel(app.MainSettingsPanel);
            app.ContourDropDownLabel.Enable = 'off';
            app.ContourDropDownLabel.Text = 'Contour:';
            app.ContourDropDown = uidropdown(app.MainSettingsPanel);
            app.ContourDropDown.Items = {};
            app.ContourDropDown.ValueChangedFcn = createCallbackFcn(app, @ContourDropDownFieldChanged, true);
            app.ContourDropDown.Enable = 'off';
            app.ContourDropDown.Tooltip = {'Contour to be analysed.'};
            app.ContourDropDown.Value = {};
            app.GroupCheckBox = uicheckbox(app.MainSettingsPanel);
            app.GroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroupCheckBoxValueChanged, true);
            app.GroupCheckBox.Tooltip = {'CHECK THIS BEFORE SELECTING A GROUP DIRECTORY. Check this if you want to analyse multiple studies. The path specified should contain folders which they themselves contain a folder named ''dicom'' with a contour file. '};
            app.GroupCheckBox.Text = 'Group?';
            app.FailedLabel = uilabel(app.LeftPanel);
            app.FailedLabel.Visible = 'off';
            app.FailedLabel.Text = 'Failed';
            app.Lamp = uilamp(app.LeftPanel);
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'Series will appear here...'};
            app.ListBox.ItemsData = {'Series will appear here...'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Tooltip = {'Hold CTRL to select multiple series.'; ''; 'Hold CTRL + SHIFT to select items between first and last click.'};
            app.ListBox.Value = {};
            app.GoButton = uibutton(app.LeftPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Tooltip = {'Analyse data '};
            app.GoButton.Text = 'Go!';
            app.CurrentDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDirectoryEditFieldValueChanged, true);
            app.CurrentDirectoryEditField.Editable = 'off';
            app.CurrentDirectoryEditField.Enable = 'off';
            app.CurrentDirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentDirectoryEditFieldLabel.Enable = 'off';
            app.CurrentDirectoryEditFieldLabel.Text = 'Current Directory:';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.InstructionsTab.ButtonDownFcn = createCallbackFcn(app, @InstructionsTabButtonDown, true);
            app.AuthorsLabel = uilabel(app.InstructionsTab);
            app.AuthorsLabel.Text = 'Authors: Jack J. Miller, James L. Kent, Marco Spartera, Antonio Stracquadanio and Aaron T. Hess.';
            app.Label = uilabel(app.InstructionsTab);
            app.Label.Text = {'1. If you wish to analyse a batch of studies, tick the ''Group'' box .'; ''; '2. Select a study directory (or group directory if ticked ''Group'') with the ''Select Directory'' button.'; ''; '3. The first study dataset will be loaded for the first contour. Once this is complete, you may choose an alternative contour or press ''Go!'' to analyse the study. '; ''; 'By default, if ''Group'' has been ticked, the software will go through and analyse every study that the group directory contains, skipping any that it cannot analyse. '};
            app.Welcometoour4DFlowPostProcessingToolboxLabel = uilabel(app.InstructionsTab);
            app.Welcometoour4DFlowPostProcessingToolboxLabel.Text = {'Welcome to our 4D Flow Post-Processing Toolbox. To get started, please ensure you have the data to be analysed stored in the correct format. Please see the example file architure below. Each study directory (folder) should contain both a DICOM directory and at least one contour file.  '; ''; ''};
            app.OCMR4DFlowPostProcessingToolboxVersion20InstructionsLabel = uilabel(app.InstructionsTab);
            app.OCMR4DFlowPostProcessingToolboxVersion20InstructionsLabel.Text = 'OCMR 4D Flow Post-Processing Toolbox Version 2.0 Instructions';
            app.ExampleFileArchitectureLabel = uilabel(app.InstructionsTab);
            app.ExampleFileArchitectureLabel.Text = 'Example File Architecture:';
            app.Tree = uitree(app.InstructionsTab);
            app.Tree.Tooltip = {'This is the example file architecture which your data should have.'};
            app.GroupDirectoryNode = uitreenode(app.Tree);
            app.GroupDirectoryNode.Text = 'GroupDirectory';
            app.Study1Node = uitreenode(app.GroupDirectoryNode);
            app.Study1Node.Text = 'Study1';
            app.DICOMNode = uitreenode(app.Study1Node);
            app.DICOMNode.Text = 'DICOM';
            app.Series1Node = uitreenode(app.DICOMNode);
            app.Series1Node.Text = 'Series1';
            app.Series2Node = uitreenode(app.DICOMNode);
            app.Series2Node.Text = 'Series2';
            app.SeriesNNode = uitreenode(app.DICOMNode);
            app.SeriesNNode.Text = 'SeriesN';
            app.contourscvi42wsxNode = uitreenode(app.Study1Node);
            app.contourscvi42wsxNode.Text = 'contours.cvi42wsx';
            app.Study2Node = uitreenode(app.GroupDirectoryNode);
            app.Study2Node.Text = 'Study2';
            app.Study3Node = uitreenode(app.GroupDirectoryNode);
            app.Study3Node.Text = 'Study3';
            app.Image = uiimage(app.InstructionsTab);
            app.Image.ImageSource = 'OCMRlogo.png';
            app.PreferencesTab = uitab(app.TabGroup);
            app.PreferencesTab.Title = 'Preferences';
            app.DialogBoxTextArea = uitextarea(app.PreferencesTab);
            app.DialogBoxTextArea.Editable = 'off';
            app.DialogBoxTextArea.Placeholder = 'Select a dataset to begin.';
            app.DialogBoxTextArea.Value = {'Select a dataset to begin.'};
            app.OxfordCentreforMagneticResonanceOCMRUniversityofOxfordLabel = uilabel(app.PreferencesTab);
            app.OxfordCentreforMagneticResonanceOCMRUniversityofOxfordLabel.Text = 'Oxford Centre for Clinical Magnetic Resonance Research (OCMR), University of Oxford';
            app.GenerateStatsFileFromSelectedButton = uibutton(app.PreferencesTab, 'push');
            app.GenerateStatsFileFromSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStatsFileFromSelectedButtonPushed, true);
            app.GenerateStatsFileFromSelectedButton.Enable = 'off';
            app.GenerateStatsFileFromSelectedButton.Text = 'Generate Stats File From Selected';
            app.ListofStudiestoAnalyseListBoxLabel = uilabel(app.PreferencesTab);
            app.ListofStudiestoAnalyseListBoxLabel.Enable = 'off';
            app.ListofStudiestoAnalyseListBoxLabel.Text = 'List of Studies to Analyse:';
            app.ListofStudiestoAnalyseListBox = uilistbox(app.PreferencesTab);
            app.ListofStudiestoAnalyseListBox.Items = {'Studies will appear here', 'Once a directory is selected'};
            app.ListofStudiestoAnalyseListBox.ItemsData = {'Studies will appear here', 'Once a directory is selected', ''};
            app.ListofStudiestoAnalyseListBox.Multiselect = 'on';
            app.ListofStudiestoAnalyseListBox.ValueChangedFcn = createCallbackFcn(app, @ListofStudiestoAnalyseListBoxValueChanged, true);
            app.ListofStudiestoAnalyseListBox.Enable = 'off';
            app.ListofStudiestoAnalyseListBox.Tooltip = {'Select/Deselect studies as required to analyse.'; ''; 'Hold ctrl to select multiple studies.'};
            app.ListofStudiestoAnalyseListBox.Value = {'Studies will appear here'};
            app.SelectAllButton = uibutton(app.PreferencesTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.SelectNoneButton = uibutton(app.PreferencesTab, 'push');
            app.SelectNoneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNoneButtonPushed, true);
            app.SelectNoneButton.Enable = 'off';
            app.SelectNoneButton.Text = 'Select None';
            app.ExportMoviePanel = uipanel(app.PreferencesTab);
            app.ExportMoviePanel.Enable = 'off';
            app.ExportMoviePanel.Title = 'Export Movie';
            app.WriteMovieButton = uibutton(app.ExportMoviePanel, 'push');
            app.WriteMovieButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMovie, true);
            app.WriteMovieButton.Icon = 'movieicon.png';
            app.WriteMovieButton.Text = 'Write Movie';
            app.FPSLabel = uilabel(app.ExportMoviePanel);
            app.FPSLabel.Text = 'FPS:';
            app.MovieFPSSpinner = uispinner(app.ExportMoviePanel);
            app.MovieFPSSpinner.Limits = [1 30];
            app.MovieFPSSpinner.ValueChangedFcn = createCallbackFcn(app, @EditMovieFPS, true);
            app.MovieFPSSpinner.Value = 15;
            app.FilenameLabel = uilabel(app.ExportMoviePanel);
            app.FilenameLabel.Text = 'Filename:';
            app.MovieFilenameEditField = uieditfield(app.ExportMoviePanel, 'text');
            app.MovieFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @EditMovieName, true);
            app.MovieFilenameEditField.Value = 'CINE.MP4';
            app.OutputSettingsPanel = uipanel(app.PreferencesTab);
            app.OutputSettingsPanel.Tooltip = {'These settings control the look of the plots.'};
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.StatisticsFileExtDropDownLabel = uilabel(app.OutputSettingsPanel);
            app.StatisticsFileExtDropDownLabel.Text = 'Statistics File Ext.';
            app.StatisticsFileExtDropDown = uidropdown(app.OutputSettingsPanel);
            app.StatisticsFileExtDropDown.Items = {'.csv', '.xlsx'};
            app.StatisticsFileExtDropDown.ValueChangedFcn = createCallbackFcn(app, @StatsFileExtensionDropDownValueChanged, true);
            app.StatisticsFileExtDropDown.Tooltip = {'File extension of the generated statistics file. Recommend  ''.xlsx'''};
            app.StatisticsFileExtDropDown.Value = '.xlsx';
            app.SaveAllPlotsCheckBox = uicheckbox(app.OutputSettingsPanel);
            app.SaveAllPlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveAllPlotsCheckBoxValueChanged, true);
            app.SaveAllPlotsCheckBox.Tooltip = {'Save all plots as png on completion. Individual figures can be saved on the seperate tabs.'};
            app.SaveAllPlotsCheckBox.Text = 'Save All Plots';
            app.MaxStreamlinestoPlotEditField = uieditfield(app.OutputSettingsPanel, 'numeric');
            app.MaxStreamlinestoPlotEditField.RoundFractionalValues = 'on';
            app.MaxStreamlinestoPlotEditField.ValueDisplayFormat = '%d';
            app.MaxStreamlinestoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @MaxStreamlinestoPlotEditFieldValueChanged, true);
            app.MaxStreamlinestoPlotEditField.Value = 50;
            app.MaxStreamlinestoPlotEditFieldLabel = uilabel(app.OutputSettingsPanel);
            app.MaxStreamlinestoPlotEditFieldLabel.Text = 'Max Streamlines to Plot';
            app.ImageTransparencyEditField = uieditfield(app.OutputSettingsPanel, 'numeric');
            app.ImageTransparencyEditField.Limits = [0 1];
            app.ImageTransparencyEditField.ValueDisplayFormat = '%1.2g';
            app.ImageTransparencyEditField.ValueChangedFcn = createCallbackFcn(app, @ImageTransparencyEditFieldValueChanged, true);
            app.ImageTransparencyEditField.Tooltip = {'Opacity in 3D visualisation'};
            app.ImageTransparencyEditField.Value = 0.2;
            app.ImageTransparencyEditFieldLabel = uilabel(app.OutputSettingsPanel);
            app.ImageTransparencyEditFieldLabel.Text = 'Image Transparency';
            app.ShowSlicesCheckBox = uicheckbox(app.OutputSettingsPanel);
            app.ShowSlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSlicesCheckBoxValueChanged, true);
            app.ShowSlicesCheckBox.Tooltip = {'Show magnitude mode atrial slices '};
            app.ShowSlicesCheckBox.Text = 'Show Slices';
            app.ShowSlicesCheckBox.Value = true;
            app.PlotSelector = uidropdown(app.OutputSettingsPanel);
            app.PlotSelector.Items = {'Streamlines', 'Vorticity Border', 'Vorticity Contours', 'Quiver'};
            app.PlotSelector.ValueChangedFcn = createCallbackFcn(app, @PlotSelectorValueChanged, true);
            app.PlotSelector.Value = 'Streamlines';
            app.FlowVisualsLabel = uilabel(app.OutputSettingsPanel);
            app.FlowVisualsLabel.Text = 'Flow Visuals';
            app.OpenCurrentDirectoryButton = uibutton(app.OutputSettingsPanel, 'push');
            app.OpenCurrentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirectory, true);
            app.OpenCurrentDirectoryButton.Icon = 'foldericon.png';
            app.OpenCurrentDirectoryButton.Enable = 'off';
            app.OpenCurrentDirectoryButton.Tooltip = {'Open the current directory in file explorer (Windows Only)'};
            app.OpenCurrentDirectoryButton.Text = 'Open Current Directory';
            app.AdvancedSettingsPanel = uipanel(app.PreferencesTab);
            app.AdvancedSettingsPanel.Title = 'Advanced Settings';
            app.AnalyseFlowCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.AnalyseFlowCheckBox.Tooltip = {'This will (re)-compute the flow vector field and derived quantites from the selected dicom dataset. '};
            app.AnalyseFlowCheckBox.Text = 'Analyse Flow';
            app.AnalyseFlowCheckBox.Value = true;
            app.WriteFlowDataCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.WriteFlowDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @WriteFlowCheckbox, true);
            app.WriteFlowDataCheckBox.Tooltip = {'This will save a (quite large) ASCII representation of the derived flow data in Ansys Ensight "case" format, suitable for viewing in ParaView or another vector field visualiser. '};
            app.WriteFlowDataCheckBox.Text = 'Write Flow Data';
            app.NoiseReductionCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.NoiseReductionCheckBox.Tooltip = {'You almost certainly want to keep this on!'};
            app.NoiseReductionCheckBox.Text = 'Noise Reduction';
            app.NoiseReductionCheckBox.Value = true;
            app.PhaseUnwrappingCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.PhaseUnwrappingCheckBox.Tooltip = {'You almost certainly want to keep this on!'};
            app.PhaseUnwrappingCheckBox.Text = 'Phase Unwrapping';
            app.PhaseUnwrappingCheckBox.Value = true;
            app.ForceReloadCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.ForceReloadCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceReloadCheckBoxValueChanged, true);
            app.ForceReloadCheckBox.Tooltip = {'This will force the reloading and identification of the dicom dataset given. '};
            app.ForceReloadCheckBox.Text = 'Force Reload';
            app.DicomFolderEditField = uieditfield(app.AdvancedSettingsPanel, 'text');
            app.DicomFolderEditField.ValueChangedFcn = createCallbackFcn(app, @DicomFolderEditFieldValueChanged, true);
            app.DicomFolderEditField.Value = 'DICOM';
            app.DicomFolderLabel = uilabel(app.AdvancedSettingsPanel);
            app.DicomFolderLabel.Text = 'Dicom Folder:';
            app.ClearDicomCacheButton = uibutton(app.AdvancedSettingsPanel, 'push');
            app.ClearDicomCacheButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDicomCacheButtonPushed, true);
            app.ClearDicomCacheButton.Text = 'Clear Dicom Cache';
            app.DICOMStudyEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.DICOMStudyEditFieldLabel.Text = 'DICOM Study:';
            app.DICOMStudyEditField = uieditfield(app.AdvancedSettingsPanel, 'numeric');
            app.DICOMStudyEditField.RoundFractionalValues = 'on';
            app.DICOMStudyEditField.ValueChangedFcn = createCallbackFcn(app, @DICOMStudyEditFieldValueChanged, true);
            app.DICOMStudyEditField.Editable = 'off';
            app.DICOMStudyEditField.Tooltip = {'This displays the DICOM study for the current flow series IDs. Only editable is series IDs are also manually entered.'};
            app.DebugErrorsCheckBox = uicheckbox(app.AdvancedSettingsPanel);
            app.DebugErrorsCheckBox.Text = 'Debug Errors';
            app.ClearLogButton = uibutton(app.PreferencesTab, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDialogBox, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.VorticityTimeTab = uitab(app.TabGroup);
            app.VorticityTimeTab.Title = 'Vorticity-Time';
            app.GridLayout2 = uigridlayout(app.VorticityTimeTab);
            app.UIAxes_OmegaT = uiaxes(app.GridLayout2);
            title(app.UIAxes_OmegaT, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_OmegaT, {''; ''})
            app.SaveVelocityPlotButton_2 = uibutton(app.GridLayout2, 'push');
            app.SaveVelocityPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveVorticityTimePlot, true);
            app.SaveVelocityPlotButton_2.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton_2.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton_2.Text = 'Save Plot';
            app.VelocityTimeTab = uitab(app.TabGroup);
            app.VelocityTimeTab.Title = 'Velocity-Time';
            app.GridLayout3 = uigridlayout(app.VelocityTimeTab);
            app.UIAxes_Vel = uiaxes(app.GridLayout3);
            title(app.UIAxes_Vel, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_Vel, {''; ''})
            app.SaveVelocityPlotButton = uibutton(app.GridLayout3, 'push');
            app.SaveVelocityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVelocityTimePlot, true);
            app.SaveVelocityPlotButton.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton.Text = 'Save Plot';
            app.VortexSizeTab = uitab(app.TabGroup);
            app.VortexSizeTab.Title = 'Vortex Size';
            app.GridLayout4 = uigridlayout(app.VortexSizeTab);
            app.UIAxes_VS = uiaxes(app.GridLayout4);
            title(app.UIAxes_VS, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_VS, {''; ''})
            app.SaveVelocityPlotButton_3 = uibutton(app.GridLayout4, 'push');
            app.SaveVelocityPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveVortexSizePlot, true);
            app.SaveVelocityPlotButton_3.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton_3.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton_3.Text = 'Save Plot';
            app.StasisHistogramTab = uitab(app.TabGroup);
            app.StasisHistogramTab.Title = 'Stasis Histogram';
            app.GridLayout5 = uigridlayout(app.StasisHistogramTab);
            app.UIAxes_SH = uiaxes(app.GridLayout5);
            title(app.UIAxes_SH, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_SH, {''; ''})
            app.SaveVelocityPlotButton_4 = uibutton(app.GridLayout5, 'push');
            app.SaveVelocityPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveStasisHistoPlot, true);
            app.SaveVelocityPlotButton_4.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton_4.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton_4.Text = 'Save Plot';
            app.VelocityHistogramTab = uitab(app.TabGroup);
            app.VelocityHistogramTab.Title = 'Velocity Histogram';
            app.GridLayout6 = uigridlayout(app.VelocityHistogramTab);
            app.UIAxes_VH = uiaxes(app.GridLayout6);
            title(app.UIAxes_VH, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_VH, {''; ''})
            app.SaveVelocityPlotButton_5 = uibutton(app.GridLayout6, 'push');
            app.SaveVelocityPlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveVelocityHistoPlot, true);
            app.SaveVelocityPlotButton_5.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton_5.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton_5.Text = 'Save Plot';
            app.FlowVisualisationTab = uitab(app.TabGroup);
            app.FlowVisualisationTab.Title = 'Flow Visualisation';
            app.GridLayout7 = uigridlayout(app.FlowVisualisationTab);
            app.UIAxes_Flow3 = uiaxes(app.GridLayout7);
            title(app.UIAxes_Flow3, 'Results will be shown here after processing!')
            ylabel(app.UIAxes_Flow3, {''; ''})
            app.SaveVelocityPlotButton_6 = uibutton(app.GridLayout7, 'push');
            app.SaveVelocityPlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveFlowVisPlot, true);
            app.SaveVelocityPlotButton_6.Icon = 'saveicon.png';
            app.SaveVelocityPlotButton_6.Tooltip = {'Save this figure'};
            app.SaveVelocityPlotButton_6.Text = 'Save Plot';
            app.FrameSlider = uislider(app.GridLayout7);
            app.FrameSlider.Limits = [1 28];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28];
            app.FrameSlider.Value = 1;
            app.IntegrateoverframesCheckBox = uicheckbox(app.GridLayout7);
            app.IntegrateoverframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegrateoverframesCheckBoxValueChanged, true);
            app.IntegrateoverframesCheckBox.Visible = 'off';
            app.IntegrateoverframesCheckBox.Text = 'Integrate over frames';
            app.FrameLabel = uilabel(app.GridLayout7);
            app.FrameLabel.Text = 'Frame';
            app.SliceSpinner = uispinner(app.GridLayout7);
            app.SliceSpinner.Limits = [0 100];
            app.SliceSpinner.ValueDisplayFormat = '%d';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Editable = 'off';
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Visible = 'off';
            app.SliceSpinner.Value = 10;
            app.SliceSpinnerLabel = uilabel(app.GridLayout7);
            app.SliceSpinnerLabel.Enable = 'off';
            app.SliceSpinnerLabel.Visible = 'off';
            app.SliceSpinnerLabel.Text = 'Slice';
            % Show the figure after all components are created
            app.OCMR4DFlowPostProcessingToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourDFlowAnalysis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OCMR4DFlowPostProcessingToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.OCMR4DFlowPostProcessingToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OCMR4DFlowPostProcessingToolUIFigure)
        end
    end
end